classdef BlindPatcherOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BlindPatcheroptionsUIFigure     matlab.ui.Figure
        LowpositivepressureEditFieldLabel  matlab.ui.control.Label
        LowpositivepressureEditField    matlab.ui.control.NumericEditField
        HighpositivepressureEditFieldLabel  matlab.ui.control.Label
        HighpositivepressureEditField   matlab.ui.control.NumericEditField
        LownegativepressureEditFieldLabel  matlab.ui.control.Label
        LownegativepressureEditField    matlab.ui.control.NumericEditField
        HighnegativepressureEditFieldLabel  matlab.ui.control.Label
        HighnegativepressureEditField   matlab.ui.control.NumericEditField
        mBarLabel                       matlab.ui.control.Label
        mBarLabel_2                     matlab.ui.control.Label
        mBarLabel_3                     matlab.ui.control.Label
        mBarLabel_4                     matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        BreakinPanel                    matlab.ui.container.Panel
        secLabel                        matlab.ui.control.Label
        MOhmLabel                       matlab.ui.control.Label
        MOhmLabel_2                     matlab.ui.control.Label
        secLabel_2                      matlab.ui.control.Label
        secLabel_3                      matlab.ui.control.Label
        secLabel_4                      matlab.ui.control.Label
        BreakindelayincreaseEditFieldLabel  matlab.ui.control.Label
        breakinDelayIncreaseEditField   matlab.ui.control.NumericEditField
        GigasealresistancevalueEditFieldLabel  matlab.ui.control.Label
        gigasealResistanceValueEditField  matlab.ui.control.NumericEditField
        SuccessfulbreakinresistancevalueEditFieldLabel  matlab.ui.control.Label
        successfulBreakinResistanceValueEditField  matlab.ui.control.NumericEditField
        MinimumdelaybeforefirstbreakinEditFieldLabel  matlab.ui.control.Label
        minimumDelayBeforeFirstBreakinEditField  matlab.ui.control.NumericEditField
        BreakinphasetotallengthEditFieldLabel  matlab.ui.control.Label
        breakinPhaseTotalLengthEditField  matlab.ui.control.NumericEditField
        InititalbreakindelayEditFieldLabel  matlab.ui.control.Label
        InititalbreakindelayEditField   matlab.ui.control.NumericEditField
        PullbackafteratteptsSpinnerLabel  matlab.ui.control.Label
        PullbackafteratteptsSpinner     matlab.ui.control.Spinner
        umLabel_2                       matlab.ui.control.Label
        PullbackdistanceEditFieldLabel  matlab.ui.control.Label
        PullbackdistanceEditField       matlab.ui.control.NumericEditField
        HuntingPanel                    matlab.ui.container.Panel
        MOhLabel                        matlab.ui.control.Label
        umsLabel                        matlab.ui.control.Label
        umLabel                         matlab.ui.control.Label
        MinRSchangetosealEditFieldLabel  matlab.ui.control.Label
        MinRSchangetosealEditField      matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        MaximumdistanceEditFieldLabel   matlab.ui.control.Label
        MaximumdistanceEditField        matlab.ui.control.NumericEditField
        CheckhitreproducibilityCheckBox  matlab.ui.control.CheckBox
        MOhLabel_2                      matlab.ui.control.Label
        ClogwarningRincreaseEditFieldLabel  matlab.ui.control.Label
        ClogwarningRincreaseEditField   matlab.ui.control.NumericEditField
        PullbackstepsforreproducibilitySpinnerLabel  matlab.ui.control.Label
        PullbackstepsforreproducibilitySpinner  matlab.ui.control.Spinner
        SealingPanel                    matlab.ui.container.Panel
        CheckRincreaseonatmosphereCheckBox  matlab.ui.control.CheckBox
        ForwardaxisButtonGroup          matlab.ui.container.ButtonGroup
        xButton                         matlab.ui.control.RadioButton
        yButton                         matlab.ui.control.RadioButton
        zButton                         matlab.ui.control.RadioButton
    end


    properties (Access = private)
        autopatcher % Autopatcher object
    end

    methods (Access = public)
    
        function initialize(app, autopatcher)
            assert(isa(autopatcher, 'AutoPatcher'));
            app.autopatcher = autopatcher;
            
            app.breakinDelayIncreaseEditField.Value = autopatcher.breakInDelayIncrease;
            app.gigasealResistanceValueEditField.Value = autopatcher.gigasealRvalue;
            app.successfulBreakinResistanceValueEditField.Value = autopatcher.successBreakInRValue;
            app.minimumDelayBeforeFirstBreakinEditField.Value = autopatcher.minDelayBeforeBreakIn;
            app.breakinPhaseTotalLengthEditField.Value = autopatcher.maxBreakInTime;
            app.MinRSchangetosealEditField.Value = autopatcher.minResistanceChangeForDetection;
            app.StepsizeEditField.Value = autopatcher.stepSize;
            app.MaximumdistanceEditField.Value = autopatcher.maxDistance;
            app.LowpositivepressureEditField.Value = autopatcher.lowPositivePressure;
            app.HighpositivepressureEditField.Value = autopatcher.highPositivePressure;
            app.LownegativepressureEditField.Value = autopatcher.lowNegativePressure;
            app.HighnegativepressureEditField.Value = autopatcher.highNegativePressure;
            app.InititalbreakindelayEditField.Value = autopatcher.initialBreakInDelay;
            app.CheckhitreproducibilityCheckBox.Value = autopatcher.checkHitReproducibility;
            app.ClogwarningRincreaseEditField.Value = autopatcher.clogWarningRIncrease;
            app.CheckRincreaseonatmosphereCheckBox.Value = autopatcher.sealingCheckAtmosphereIncrease;
            app.PullbackstepsforreproducibilitySpinner.Value = autopatcher.pullBackSteps;
            app.PullbackafteratteptsSpinner.Value = autopatcher.breakInPullBackAfterAttempts;
            app.PullbackdistanceEditField.Value = autopatcher.breakInPullBackDistance;
            app.setCurrentForwardAxisValue();
        end
        
    end
    
    methods (Access = private, Static)
    
        function error = failsafeSetValue(obj, newValue)
            error = 0;
            try
                obj.Value = newValue;
            catch ex
                error = 1;
            end
        end
        
    end
    
    methods (Access = private)
        
        function setCurrentForwardAxisValue(app)
            try
                currentValue = app.autopatcher.forwardAxis;
                switch currentValue
                    case 'x'
                        app.ForwardaxisButtonGroup.SelectedObject = app.ForwardaxisButtonGroup.Buttons(1);
                    case 'y'
                        app.ForwardaxisButtonGroup.SelectedObject = app.ForwardaxisButtonGroup.Buttons(2);
                    case 'z'
                        app.ForwardaxisButtonGroup.SelectedObject = app.ForwardaxisButtonGroup.Buttons(3);
                    otherwise
                        error('Current ''forwardAxis'' value of AutoPatcher is invalid/unsupported.');
                end
            catch ex2
                log4m.getLogger().warn(['Could not set graphical elements value. Check if button texts have changed. Msg: ', ex2.message]);
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: CheckRincreaseonatmosphereCheckBox
        function CheckRincreaseonatmosphereCheckBoxValueChanged(app, event)
            value = app.CheckRincreaseonatmosphereCheckBox.Value;
            try
                app.autopatcher.sealingCheckAtmosphereIncrease = value;
            catch ex
                app.failsafeSetValue(app.CheckRincreaseonatmosphereCheckBox, app.autopatcher.sealingCheckAtmosphereIncrease);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: CheckhitreproducibilityCheckBox
        function CheckhitreproducibilityCheckBoxValueChanged(app, event)
            value = app.CheckhitreproducibilityCheckBox.Value;
            try
                app.autopatcher.checkHitReproducibility = value;
            catch ex
                app.failsafeSetValue(app.CheckhitreproducibilityCheckBox, app.autopatcher.checkHitReproducibility);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: ClogwarningRincreaseEditField
        function ClogwarningRincreaseEditFieldValueChanged(app, event)
            value = app.ClogwarningRincreaseEditField.Value;
            try
                app.autopatcher.clogWarningRIncrease = value;
            catch ex
                app.failsafeSetValue(app.ClogwarningRincreaseEditField, app.autopatcher.clogWarningRIncrease);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Selection changed function: ForwardaxisButtonGroup
        function ForwardaxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.ForwardaxisButtonGroup.SelectedObject;
            
            value = selectedButton.Text;
            try
                app.autopatcher.forwardAxis = value;
            catch ex
                app.setCurrentForwardAxisValue();
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: HighnegativepressureEditField
        function HighnegativepressureEditFieldValueChanged(app, event)
            value = app.HighnegativepressureEditField.Value;
            try
                app.autopatcher.highNegativePressure = value;
            catch ex
                app.failsafeSetValue(app.HighnegativepressureEditField, app.autopatcher.highNegativePressure);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: HighpositivepressureEditField
        function HighpositivepressureEditFieldValueChanged(app, event)
            value = app.HighpositivepressureEditField.Value;
            try
                app.autopatcher.highPositivePressure = value;
            catch ex
                app.failsafeSetValue(app.HighpositivepressureEditField, app.autopatcher.highPositivePressure);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: InititalbreakindelayEditField
        function InititalbreakindelayEditFieldValueChanged(app, event)
            value = app.InititalbreakindelayEditField.Value;
            try
                app.autopatcher.initialBreakInDelay = value;
            catch ex
                app.failsafeSetValue(app.InititalbreakindelayEditField, app.autopatcher.initialBreakInDelay);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: LownegativepressureEditField
        function LownegativepressureEditFieldValueChanged(app, event)
            value = app.LownegativepressureEditField.Value;
            try
                app.autopatcher.lowNegativePressure = value;
            catch ex
                app.failsafeSetValue(app.LownegativepressureEditField, app.autopatcher.lowNegativePressure);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: LowpositivepressureEditField
        function LowpositivepressureEditFieldValueChanged(app, event)
            value = app.LowpositivepressureEditField.Value;
            try
                app.autopatcher.lowPositivePressure = value;
            catch ex
                app.failsafeSetValue(app.LowpositivepressureEditField, app.autopatcher.lowPositivePressure);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: MaximumdistanceEditField
        function MaximumdistanceEditFieldValueChanged(app, event)
            value = app.MaximumdistanceEditField.Value;
            try
                app.autopatcher.maxDistance = value;
            catch ex
                app.failsafeSetValue(app.MaximumdistanceEditField, app.autopatcher.maxDistance);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: MinRSchangetosealEditField
        function MinRSchangetosealEditFieldValueChanged(app, event)
            value = app.MinRSchangetosealEditField.Value;
            try
                app.autopatcher.minResistanceChangeForDetection = value;
            catch ex
                app.failsafeSetValue(app.MinRSchangetosealEditField, app.autopatcher.minResistanceChangeForDetection);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.delete();
        end

        % Value changed function: PullbackafteratteptsSpinner
        function PullbackafteratteptsSpinnerValueChanged(app, event)
            value = app.PullbackafteratteptsSpinner.Value;
            try
                app.autopatcher.breakInPullBackAfterAttempts = value;
            catch ex
                app.failsafeSetValue(app.PullbackafteratteptsSpinner, app.autopatcher.breakInPullBackAfterAttempts);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: PullbackdistanceEditField
        function PullbackdistanceEditFieldValueChanged(app, event)
            value = app.PullbackdistanceEditField.Value;
            try
                app.autopatcher.breakInPullBackDistance = value;
            catch ex
                app.failsafeSetValue(app.PullbackdistanceEditField, app.autopatcher.breakInPullBackDistance);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: 
        % PullbackstepsforreproducibilitySpinner
        function PullbackstepsforreproducibilitySpinnerValueChanged(app, event)
            value = app.PullbackstepsforreproducibilitySpinner.Value;
            try
                app.autopatcher.pullBackSteps = value;
            catch ex
                app.failsafeSetValue(app.PullbackstepsforreproducibilitySpinner, app.autopatcher.pullBackSteps);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: StepsizeEditField
        function StepsizeEditFieldValueChanged(app, event)
            value = app.StepsizeEditField.Value;
            try
                app.autopatcher.stepSize = value;
            catch ex
                app.failsafeSetValue(app.StepsizeEditField, app.autopatcher.stepSize);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: breakinDelayIncreaseEditField
        function breakinDelayIncreaseEditFieldValueChanged(app, event)
            value = app.breakinDelayIncreaseEditField.Value;
            try
                app.autopatcher.breakInDelayIncrease = value;
            catch ex
                app.failsafeSetValue(app.breakinDelayIncreaseEditField, app.autopatcher.breakInDelayIncrease);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: breakinPhaseTotalLengthEditField
        function breakinPhaseTotalLengthEditFieldValueChanged(app, event)
            value = app.breakinPhaseTotalLengthEditField.Value;
            try
                app.autopatcher.maxBreakInTime = value;
            catch ex
                app.failsafeSetValue(app.breakinPhaseTotalLengthEditField, app.autopatcher.maxBreakInTime);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: gigasealResistanceValueEditField
        function gigasealResistanceValueEditFieldValueChanged(app, event)
            value = app.gigasealResistanceValueEditField.Value;
            try
                app.autopatcher.gigasealRvalue = value;
            catch ex
                app.failsafeSetValue(app.gigasealResistanceValueEditField, app.autopatcher.gigasealRvalue);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: 
        % minimumDelayBeforeFirstBreakinEditField
        function minimumDelayBeforeFirstBreakinEditFieldValueChanged(app, event)
            value = app.minimumDelayBeforeFirstBreakinEditField.Value;
            try
                app.autopatcher.minDelayBeforeBreakIn = value;
            catch ex
                app.failsafeSetValue(app.minimumDelayBeforeFirstBreakinEditField, app.autopatcher.minDelayBeforeBreakIn);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end

        % Value changed function: 
        % successfulBreakinResistanceValueEditField
        function successfulBreakinResistanceValueEditFieldValueChanged(app, event)
            value = app.successfulBreakinResistanceValueEditField.Value;
            try
                app.autopatcher.successBreakInRValue = value;
            catch ex
                app.failsafeSetValue(app.successfulBreakinResistanceValueEditField, app.autopatcher.successBreakInRValue);
                uialert(app.BlindPatcheroptionsUIFigure, ['Error while setting autopatcher parameter: ', ex.message], 'Blind Patcher options', 'Icon', 'error');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BlindPatcheroptionsUIFigure = uifigure;
            app.BlindPatcheroptionsUIFigure.Name = 'Blind Patcher options';
            app.LowpositivepressureEditFieldLabel = uilabel(app.BlindPatcheroptionsUIFigure);
            app.LowpositivepressureEditFieldLabel.Text = 'Low positive pressure:';
            app.LowpositivepressureEditField = uieditfield(app.BlindPatcheroptionsUIFigure, 'numeric');
            app.LowpositivepressureEditField.ValueChangedFcn = createCallbackFcn(app, @LowpositivepressureEditFieldValueChanged, true);
            app.LowpositivepressureEditField.Limits = [0 Inf];
            app.HighpositivepressureEditFieldLabel = uilabel(app.BlindPatcheroptionsUIFigure);
            app.HighpositivepressureEditFieldLabel.Text = 'High positive pressure:';
            app.HighpositivepressureEditField = uieditfield(app.BlindPatcheroptionsUIFigure, 'numeric');
            app.HighpositivepressureEditField.ValueChangedFcn = createCallbackFcn(app, @HighpositivepressureEditFieldValueChanged, true);
            app.HighpositivepressureEditField.Limits = [0 Inf];
            app.LownegativepressureEditFieldLabel = uilabel(app.BlindPatcheroptionsUIFigure);
            app.LownegativepressureEditFieldLabel.Text = 'Low negative pressure:';
            app.LownegativepressureEditField = uieditfield(app.BlindPatcheroptionsUIFigure, 'numeric');
            app.LownegativepressureEditField.ValueChangedFcn = createCallbackFcn(app, @LownegativepressureEditFieldValueChanged, true);
            app.HighnegativepressureEditFieldLabel = uilabel(app.BlindPatcheroptionsUIFigure);
            app.HighnegativepressureEditFieldLabel.Text = 'High negative pressure:';
            app.HighnegativepressureEditField = uieditfield(app.BlindPatcheroptionsUIFigure, 'numeric');
            app.HighnegativepressureEditField.ValueChangedFcn = createCallbackFcn(app, @HighnegativepressureEditFieldValueChanged, true);
            app.mBarLabel = uilabel(app.BlindPatcheroptionsUIFigure);
            app.mBarLabel.Text = 'mBar';
            app.mBarLabel_2 = uilabel(app.BlindPatcheroptionsUIFigure);
            app.mBarLabel_2.Text = 'mBar';
            app.mBarLabel_3 = uilabel(app.BlindPatcheroptionsUIFigure);
            app.mBarLabel_3.Text = 'mBar';
            app.mBarLabel_4 = uilabel(app.BlindPatcheroptionsUIFigure);
            app.mBarLabel_4.Text = 'mBar';
            app.OKButton = uibutton(app.BlindPatcheroptionsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.BreakinPanel = uipanel(app.BlindPatcheroptionsUIFigure);
            app.BreakinPanel.Title = 'Break-in';
            app.secLabel = uilabel(app.BreakinPanel);
            app.secLabel.Text = 'sec';
            app.MOhmLabel = uilabel(app.BreakinPanel);
            app.MOhmLabel.Text = 'MOhm';
            app.MOhmLabel_2 = uilabel(app.BreakinPanel);
            app.MOhmLabel_2.Text = 'MOhm';
            app.secLabel_2 = uilabel(app.BreakinPanel);
            app.secLabel_2.Text = 'sec';
            app.secLabel_3 = uilabel(app.BreakinPanel);
            app.secLabel_3.Text = 'sec';
            app.secLabel_4 = uilabel(app.BreakinPanel);
            app.secLabel_4.Text = 'sec';
            app.BreakindelayincreaseEditFieldLabel = uilabel(app.BreakinPanel);
            app.BreakindelayincreaseEditFieldLabel.Text = 'Break-in delay increase:';
            app.breakinDelayIncreaseEditField = uieditfield(app.BreakinPanel, 'numeric');
            app.breakinDelayIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @breakinDelayIncreaseEditFieldValueChanged, true);
            app.breakinDelayIncreaseEditField.Limits = [0 Inf];
            app.GigasealresistancevalueEditFieldLabel = uilabel(app.BreakinPanel);
            app.GigasealresistancevalueEditFieldLabel.Text = 'Gigaseal resistance value:';
            app.gigasealResistanceValueEditField = uieditfield(app.BreakinPanel, 'numeric');
            app.gigasealResistanceValueEditField.ValueChangedFcn = createCallbackFcn(app, @gigasealResistanceValueEditFieldValueChanged, true);
            app.gigasealResistanceValueEditField.Limits = [0 Inf];
            app.SuccessfulbreakinresistancevalueEditFieldLabel = uilabel(app.BreakinPanel);
            app.SuccessfulbreakinresistancevalueEditFieldLabel.Text = 'Successful break-in resistance value:';
            app.successfulBreakinResistanceValueEditField = uieditfield(app.BreakinPanel, 'numeric');
            app.successfulBreakinResistanceValueEditField.ValueChangedFcn = createCallbackFcn(app, @successfulBreakinResistanceValueEditFieldValueChanged, true);
            app.successfulBreakinResistanceValueEditField.Limits = [0 Inf];
            app.MinimumdelaybeforefirstbreakinEditFieldLabel = uilabel(app.BreakinPanel);
            app.MinimumdelaybeforefirstbreakinEditFieldLabel.Text = 'Minimum delay before first break-in:';
            app.minimumDelayBeforeFirstBreakinEditField = uieditfield(app.BreakinPanel, 'numeric');
            app.minimumDelayBeforeFirstBreakinEditField.ValueChangedFcn = createCallbackFcn(app, @minimumDelayBeforeFirstBreakinEditFieldValueChanged, true);
            app.minimumDelayBeforeFirstBreakinEditField.Limits = [0 Inf];
            app.BreakinphasetotallengthEditFieldLabel = uilabel(app.BreakinPanel);
            app.BreakinphasetotallengthEditFieldLabel.Text = 'Break-in phase total length:';
            app.breakinPhaseTotalLengthEditField = uieditfield(app.BreakinPanel, 'numeric');
            app.breakinPhaseTotalLengthEditField.ValueChangedFcn = createCallbackFcn(app, @breakinPhaseTotalLengthEditFieldValueChanged, true);
            app.breakinPhaseTotalLengthEditField.Limits = [0 Inf];
            app.InititalbreakindelayEditFieldLabel = uilabel(app.BreakinPanel);
            app.InititalbreakindelayEditFieldLabel.Text = 'Initital break-in delay:';
            app.InititalbreakindelayEditField = uieditfield(app.BreakinPanel, 'numeric');
            app.InititalbreakindelayEditField.ValueChangedFcn = createCallbackFcn(app, @InititalbreakindelayEditFieldValueChanged, true);
            app.InititalbreakindelayEditField.Limits = [0 Inf];
            app.PullbackafteratteptsSpinnerLabel = uilabel(app.BreakinPanel);
            app.PullbackafteratteptsSpinnerLabel.Text = 'Pull back after attepts';
            app.PullbackafteratteptsSpinner = uispinner(app.BreakinPanel);
            app.PullbackafteratteptsSpinner.ValueChangedFcn = createCallbackFcn(app, @PullbackafteratteptsSpinnerValueChanged, true);
            app.PullbackafteratteptsSpinner.Limits = [0 Inf];
            app.PullbackafteratteptsSpinner.RoundFractionalValues = 'on';
            app.umLabel_2 = uilabel(app.BreakinPanel);
            app.umLabel_2.Text = 'um';
            app.PullbackdistanceEditFieldLabel = uilabel(app.BreakinPanel);
            app.PullbackdistanceEditFieldLabel.Text = 'Pull back distance';
            app.PullbackdistanceEditField = uieditfield(app.BreakinPanel, 'numeric');
            app.PullbackdistanceEditField.ValueChangedFcn = createCallbackFcn(app, @PullbackdistanceEditFieldValueChanged, true);
            app.PullbackdistanceEditField.Limits = [0 Inf];
            app.HuntingPanel = uipanel(app.BlindPatcheroptionsUIFigure);
            app.HuntingPanel.Title = 'Hunting';
            app.MOhLabel = uilabel(app.HuntingPanel);
            app.MOhLabel.Text = 'MOh';
            app.umsLabel = uilabel(app.HuntingPanel);
            app.umsLabel.Text = 'um/s';
            app.umLabel = uilabel(app.HuntingPanel);
            app.umLabel.Text = 'um';
            app.MinRSchangetosealEditFieldLabel = uilabel(app.HuntingPanel);
            app.MinRSchangetosealEditFieldLabel.Text = 'Min. RS change to seal:';
            app.MinRSchangetosealEditField = uieditfield(app.HuntingPanel, 'numeric');
            app.MinRSchangetosealEditField.ValueChangedFcn = createCallbackFcn(app, @MinRSchangetosealEditFieldValueChanged, true);
            app.MinRSchangetosealEditField.Limits = [0 Inf];
            app.StepsizeEditFieldLabel = uilabel(app.HuntingPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size:';
            app.StepsizeEditField = uieditfield(app.HuntingPanel, 'numeric');
            app.StepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeEditFieldValueChanged, true);
            app.StepsizeEditField.Limits = [0 Inf];
            app.MaximumdistanceEditFieldLabel = uilabel(app.HuntingPanel);
            app.MaximumdistanceEditFieldLabel.Text = 'Maximum distance:';
            app.MaximumdistanceEditField = uieditfield(app.HuntingPanel, 'numeric');
            app.MaximumdistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumdistanceEditFieldValueChanged, true);
            app.MaximumdistanceEditField.Limits = [0 Inf];
            app.CheckhitreproducibilityCheckBox = uicheckbox(app.HuntingPanel);
            app.CheckhitreproducibilityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckhitreproducibilityCheckBoxValueChanged, true);
            app.CheckhitreproducibilityCheckBox.Text = 'Check hit reproducibility';
            app.MOhLabel_2 = uilabel(app.HuntingPanel);
            app.MOhLabel_2.Text = 'MOh';
            app.ClogwarningRincreaseEditFieldLabel = uilabel(app.HuntingPanel);
            app.ClogwarningRincreaseEditFieldLabel.Text = 'Clog warning R increase';
            app.ClogwarningRincreaseEditField = uieditfield(app.HuntingPanel, 'numeric');
            app.ClogwarningRincreaseEditField.ValueChangedFcn = createCallbackFcn(app, @ClogwarningRincreaseEditFieldValueChanged, true);
            app.ClogwarningRincreaseEditField.Limits = [0 Inf];
            app.PullbackstepsforreproducibilitySpinnerLabel = uilabel(app.HuntingPanel);
            app.PullbackstepsforreproducibilitySpinnerLabel.Text = 'Pull back steps for reproducibility';
            app.PullbackstepsforreproducibilitySpinner = uispinner(app.HuntingPanel);
            app.PullbackstepsforreproducibilitySpinner.ValueChangedFcn = createCallbackFcn(app, @PullbackstepsforreproducibilitySpinnerValueChanged, true);
            app.PullbackstepsforreproducibilitySpinner.Limits = [1 Inf];
            app.PullbackstepsforreproducibilitySpinner.RoundFractionalValues = 'on';
            app.PullbackstepsforreproducibilitySpinner.Value = 1;
            app.SealingPanel = uipanel(app.BlindPatcheroptionsUIFigure);
            app.SealingPanel.Title = 'Sealing';
            app.CheckRincreaseonatmosphereCheckBox = uicheckbox(app.SealingPanel);
            app.CheckRincreaseonatmosphereCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckRincreaseonatmosphereCheckBoxValueChanged, true);
            app.CheckRincreaseonatmosphereCheckBox.Text = 'Check R increase on atmosphere';
            app.ForwardaxisButtonGroup = uibuttongroup(app.BlindPatcheroptionsUIFigure);
            app.ForwardaxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ForwardaxisButtonGroupSelectionChanged, true);
            app.ForwardaxisButtonGroup.Title = '''Forward'' axis';
            app.xButton = uiradiobutton(app.ForwardaxisButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.ForwardaxisButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.ForwardaxisButtonGroup);
            app.zButton.Text = 'z';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BlindPatcherOptions

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BlindPatcheroptionsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BlindPatcheroptionsUIFigure)
        end
    end
end