classdef CleanGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CleanGUIUIFigure        matlab.ui.Figure
        CleanpipetteButton      matlab.ui.control.Button
        SetpositionsPanel       matlab.ui.container.Panel
        DrawbackpositionButton  matlab.ui.control.Button
        AlconoxdishButton       matlab.ui.control.Button
        aCSFdishButton          matlab.ui.control.Button
    end


    properties (Access = private)
        pipetteCleaner
    end

    methods (Access = public)
    
        function initialize(app, pipetteCleaner)
            assert(isa(pipetteCleaner, 'PipetteCleaner'));
            app.pipetteCleaner = pipetteCleaner;
        end
    end


    methods (Access = private)

        % Button pushed function: AlconoxdishButton
        function AlconoxdishButtonPushed(app, event)
            app.pipetteCleaner.saveCurrentPositionForAlconox();
        end

        % Button pushed function: CleanpipetteButton
        function CleanpipetteButtonPushed(app, event)
            app.pipetteCleaner.cleanPipette();
        end

        % Button pushed function: DrawbackpositionButton
        function DrawbackpositionButtonPushed(app, event)
            app.pipetteCleaner.saveCurrentPositionForDrawback();
        end

        % Button pushed function: aCSFdishButton
        function aCSFdishButtonPushed(app, event)
            app.pipetteCleaner.saveCurrentPositionForAcsf();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CleanGUIUIFigure = uifigure;
            app.CleanGUIUIFigure.Name = 'Pipette Cleaner';
            app.CleanpipetteButton = uibutton(app.CleanGUIUIFigure, 'push');
            app.CleanpipetteButton.ButtonPushedFcn = createCallbackFcn(app, @CleanpipetteButtonPushed, true);
            app.CleanpipetteButton.Text = 'Clean pipette';
            app.SetpositionsPanel = uipanel(app.CleanGUIUIFigure);
            app.SetpositionsPanel.Title = 'Set positions';
            app.DrawbackpositionButton = uibutton(app.SetpositionsPanel, 'push');
            app.DrawbackpositionButton.ButtonPushedFcn = createCallbackFcn(app, @DrawbackpositionButtonPushed, true);
            app.DrawbackpositionButton.Text = 'Drawback position';
            app.AlconoxdishButton = uibutton(app.SetpositionsPanel, 'push');
            app.AlconoxdishButton.ButtonPushedFcn = createCallbackFcn(app, @AlconoxdishButtonPushed, true);
            app.AlconoxdishButton.Text = 'Alconox dish';
            app.aCSFdishButton = uibutton(app.SetpositionsPanel, 'push');
            app.aCSFdishButton.ButtonPushedFcn = createCallbackFcn(app, @aCSFdishButtonPushed, true);
            app.aCSFdishButton.Text = 'aCSF dish';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CleanGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CleanGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CleanGUIUIFigure)
        end
    end
end