classdef DcamOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CameraOptionsFigure       matlab.ui.Figure
        NameLabel                 matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        ShutterEditFieldLabel     matlab.ui.control.Label
        ShutterEditField          matlab.ui.control.NumericEditField
        GainEditFieldLabel        matlab.ui.control.Label
        GainEditField             matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel  matlab.ui.control.Label
        BrightnessEditField       matlab.ui.control.NumericEditField
        ContrastEditFieldLabel    matlab.ui.control.Label
        ContrastEditField         matlab.ui.control.NumericEditField
        GammaEditFieldLabel       matlab.ui.control.Label
        GammaEditField            matlab.ui.control.NumericEditField
        ExposureEditFieldLabel    matlab.ui.control.Label
        ExposureEditField         matlab.ui.control.NumericEditField
        SharpnessEditFieldLabel   matlab.ui.control.Label
        SharpnessEditField        matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel  matlab.ui.control.Label
        ResolutionEditField       matlab.ui.control.EditField
        OKButton                  matlab.ui.control.Button
        TestButton                matlab.ui.control.Button
    end


    properties (Access = private)
        cam % camera object
    end

    methods (Access = public)
    
        function init(app, cam)
            app.cam = cam;
            try
                app.NameEditField.Value = app.cam.Name;
            catch ex
                app.NameEditField.Enable = 'off';
            end
            
            try
                app.ResolutionEditField.Value = app.cam.Resolution;
            catch ex
                app.ResolutionEditField.Enable = 'off';
            end
            
            try
                app.BrightnessEditField.Value = app.cam.Brightness;
            catch ex
                app.BrightnessEditField.Enable = 'off';
            end
            
            try
                app.ContrastEditField.Value = app.cam.Contrast;
            catch ex
                app.ContrastEditField.Enable = 'off';
            end
            
            try
                app.ExposureEditField.Value = app.cam.Exposure;
            catch ex
                app.ExposureEditField.Enable = 'off';
            end
            
            try
                app.GainEditField.Value = app.cam.Gain;
            catch ex
                app.GainEditField.Enable = 'off';
            end
            
            try
                app.GammaEditField.Value = app.cam.Gamma;
            catch ex
                app.GammaEditField.Enable = 'off';
            end
            
            try
                app.SharpnessEditField.Value = app.cam.Sharpness;
            catch ex
                app.SharpnessEditField.Enable = 'off';
            end
            
            try
                app.ShutterEditField.Value = app.cam.Shutter;
            catch ex
                app.ShutterEditField.Enable = 'off';
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app.CameraOptionsFigure);
        end

        % Value changed function: BrightnessEditField
        function BrightnessEditFieldValueChanged(app, event)
            value = app.BrightnessEditField.Value;
            try
                app.cam.Brightness = value;
            catch ex
                app.BrightnessEditField.Value = event.PreviousValue;
                uialert(app.CameraOptionsFigure, ex.message, 'Camera Options', 'Icon', 'error');
            end
        end

        % Value changed function: ContrastEditField
        function ContrastEditFieldValueChanged(app, event)
            value = app.ContrastEditField.Value;
            try
                app.cam.Contrast = value;
            catch ex
                app.ContrastEditField.Value = event.PreviousValue;
                uialert(app.CameraOptionsFigure, ex.message, 'Camera Options', 'Icon', 'error');
            end
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)
            value = app.ExposureEditField.Value;
            try
                app.cam.Exposure = value;
            catch ex
                app.ExposureEditField.Value = event.PreviousValue;
                uialert(app.CameraOptionsFigure, ex.message, 'Camera Options', 'Icon', 'error');
            end
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            try
                app.cam.Gain = value;
            catch ex
                app.GainEditField.Value = event.PreviousValue;
                uialert(app.CameraOptionsFigure, ex.message, 'Camera Options', 'Icon', 'error');
            end
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            value = app.GammaEditField.Value;
            try
                app.cam.Gamma = value;
            catch ex
                app.GammaEditField.Value = event.PreviousValue;
                uialert(app.CameraOptionsFigure, ex.message, 'Camera Options', 'Icon', 'error');
            end
        end

        % Value changed function: SharpnessEditField
        function SharpnessEditFieldValueChanged(app, event)
            value = app.SharpnessEditField.Value;
            try
                app.cam.Sharpness = value;
            catch ex
                app.SharpnessEditField.Value = event.PreviousValue;
                uialert(app.CameraOptionsFigure, ex.message, 'Camera Options', 'Icon', 'error');
            end
        end

        % Value changed function: ShutterEditField
        function ShutterEditFieldValueChanged(app, event)
            value = app.ShutterEditField.Value;
            try
                app.cam.Shutter = value;
            catch ex
                app.ShutterEditField.Value = event.PreviousValue;
                uialert(app.CameraOptionsFigure, ex.message, 'Camera Options', 'Icon', 'error');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CameraOptionsFigure = uifigure;
            app.CameraOptionsFigure.Name = 'Camera Options';
            app.NameLabel = uilabel(app.CameraOptionsFigure);
            app.NameLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.CameraOptionsFigure, 'text');
            app.NameEditField.Editable = 'off';
            app.ShutterEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.ShutterEditFieldLabel.Text = 'Shutter';
            app.ShutterEditField = uieditfield(app.CameraOptionsFigure, 'numeric');
            app.ShutterEditField.ValueChangedFcn = createCallbackFcn(app, @ShutterEditFieldValueChanged, true);
            app.GainEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.CameraOptionsFigure, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.BrightnessEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.CameraOptionsFigure, 'numeric');
            app.BrightnessEditField.ValueChangedFcn = createCallbackFcn(app, @BrightnessEditFieldValueChanged, true);
            app.ContrastEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.CameraOptionsFigure, 'numeric');
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.GammaEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.CameraOptionsFigure, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.ExposureEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.CameraOptionsFigure, 'numeric');
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.SharpnessEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.SharpnessEditFieldLabel.Text = 'Sharpness';
            app.SharpnessEditField = uieditfield(app.CameraOptionsFigure, 'numeric');
            app.SharpnessEditField.ValueChangedFcn = createCallbackFcn(app, @SharpnessEditFieldValueChanged, true);
            app.ResolutionEditFieldLabel = uilabel(app.CameraOptionsFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.CameraOptionsFigure, 'text');
            app.ResolutionEditField.Editable = 'off';
            app.OKButton = uibutton(app.CameraOptionsFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TestButton = uibutton(app.CameraOptionsFigure, 'push');
            app.TestButton.Text = 'Test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DcamOptions

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CameraOptionsFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CameraOptionsFigure)
        end
    end
end