classdef DiaryGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatchClampDiaryUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SliceinfoTab                    matlab.ui.container.Tab
        AddholderpositionhereButton     matlab.ui.control.Button
        AddsamplesidepositionhereButton  matlab.ui.control.Button
        sliceInfoAxes                   matlab.ui.control.UIAxes
        RefreshButton                   matlab.ui.control.Button
        NewDiaryFileButton              matlab.ui.control.Button
        MarklastattemptasLabel          matlab.ui.control.Label
        SuccessButton                   matlab.ui.control.Button
        FailureButton                   matlab.ui.control.Button
        MarkmanualapproachButton        matlab.ui.control.Button
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        PopoutButton                    matlab.ui.control.Button
        JEMsliceinfoTab                 matlab.ui.container.Tab
        JEMversionEditFieldLabel        matlab.ui.control.Label
        JEMversionEditField             matlab.ui.control.EditField
        ResearcherDropDownLabel         matlab.ui.control.Label
        ResearcherDropDown              matlab.ui.control.DropDown
        RigDropDownLabel                matlab.ui.control.Label
        RigDropDown                     matlab.ui.control.DropDown
        SliceonrigdatetimeEditFieldLabel  matlab.ui.control.Label
        SliceonrigdatetimeEditField     matlab.ui.control.EditField
        NowButton                       matlab.ui.control.Button
        SliceIDEditFieldLabel           matlab.ui.control.Label
        SliceIDEditField                matlab.ui.control.EditField
        ACSFDateEditFieldLabel          matlab.ui.control.Label
        ACSFDateEditField               matlab.ui.control.EditField
        BlankFillDateEditFieldLabel     matlab.ui.control.Label
        BlankFillDateEditField          matlab.ui.control.EditField
        InternalFillDateEditFieldLabel  matlab.ui.control.Label
        InternalFillDateEditField       matlab.ui.control.EditField
        TodayButton                     matlab.ui.control.Button
        TodayButton_2                   matlab.ui.control.Button
        TodayButton_3                   matlab.ui.control.Button
        SliceisflippedCheckBox          matlab.ui.control.CheckBox
        SliceQualityPanel               matlab.ui.container.Panel
        GoodCheckBox                    matlab.ui.control.CheckBox
        DamagedCheckBox                 matlab.ui.control.CheckBox
        UnevenThicknessCheckBox         matlab.ui.control.CheckBox
        WaveofDeathCheckBox             matlab.ui.control.CheckBox
        ExtraSliceNotesTextAreaLabel    matlab.ui.control.Label
        ExtraSliceNotesTextArea         matlab.ui.control.TextArea
        AddLogEntryButton               matlab.ui.control.Button
        PatchSeqPipetteTab              matlab.ui.container.Tab
        ApproachInfoPanel               matlab.ui.container.Panel
        SliceHealthButtonGroup          matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        CellHealthButtonGroup           matlab.ui.container.ButtonGroup
        Button_6                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        Button_8                        matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        Button_10                       matlab.ui.control.RadioButton
        CreStatusButtonGroup            matlab.ui.container.ButtonGroup
        CreButton                       matlab.ui.control.RadioButton
        CreButton_2                     matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        PilotNameDropDownLabel          matlab.ui.control.Label
        PilotNameDropDown               matlab.ui.control.DropDown
        CellDepthEditFieldLabel         matlab.ui.control.Label
        CellDepthEditField              matlab.ui.control.EditField
        ROIDropDownLabel                matlab.ui.control.Label
        ROIDropDown                     matlab.ui.control.DropDown
        LayerDropDown                   matlab.ui.control.DropDown
        ResetButton                     matlab.ui.control.Button
        RecordingInfoPanel              matlab.ui.container.Panel
        ApproachStartEditFieldLabel     matlab.ui.control.Label
        ApproachStartEditField          matlab.ui.control.EditField
        PipetteRMOhmEditFieldLabel      matlab.ui.control.Label
        PipetteRMOhmEditField           matlab.ui.control.EditField
        WholeCellStartEditFieldLabel    matlab.ui.control.Label
        WholeCellStartEditField         matlab.ui.control.EditField
        CellTypePredictionforHumanNeuronsDropDownLabel  matlab.ui.control.Label
        CellTypePredictionforHumanNeuronsDropDown  matlab.ui.control.DropDown
        SuccessFailureTabGroup          matlab.ui.container.TabGroup
        SuccessTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        RecordingTab                    matlab.ui.container.Tab
        RecordingObservationsPanel      matlab.ui.container.Panel
        PatchCellUnstableCheckBox       matlab.ui.control.CheckBox
        PatchBecameLeakyCheckBox        matlab.ui.control.CheckBox
        AccessResistanceIncreasedCheckBox  matlab.ui.control.CheckBox
        CellDepolarizedCheckBox         matlab.ui.control.CheckBox
        CellHyperpolarizedCheckBox      matlab.ui.control.CheckBox
        BlowoutVoltageOutofRangeCheckBox  matlab.ui.control.CheckBox
        RheobaseChangedCheckBox         matlab.ui.control.CheckBox
        EndedAfterC1CheckBox            matlab.ui.control.CheckBox
        WaveofDeathCheckBox_3           matlab.ui.control.CheckBox
        TrainingPracticeCheckBox        matlab.ui.control.CheckBox
        RigSoftwareProblemsCheckBox     matlab.ui.control.CheckBox
        QCNotesTextAreaLabel            matlab.ui.control.Label
        QCNotesTextArea                 matlab.ui.control.TextArea
        BadSweepsTextAreaLabel          matlab.ui.control.Label
        BadSweepsTextArea               matlab.ui.control.TextArea
        ExtractionTab                   matlab.ui.container.Tab
        ExtractionpressurembarEditFieldLabel  matlab.ui.control.Label
        ExtractionpressurembarEditField  matlab.ui.control.NumericEditField
        RetractionpressurembarEditFieldLabel  matlab.ui.control.Label
        RetractionpressurembarEditField  matlab.ui.control.NumericEditField
        ExtractionStartEditFieldLabel   matlab.ui.control.Label
        ExtractionStartEditField        matlab.ui.control.EditField
        NowButton_2                     matlab.ui.control.Button
        NowButton_3                     matlab.ui.control.Button
        RetractionStartEditFieldLabel   matlab.ui.control.Label
        RetractionStartEditField        matlab.ui.control.EditField
        NowButton_4                     matlab.ui.control.Button
        RetractionEndEditFieldLabel     matlab.ui.control.Label
        RetractionEndEditField          matlab.ui.control.EditField
        PostPatchPipetteREditFieldLabel  matlab.ui.control.Label
        PostPatchPipetteREditField      matlab.ui.control.NumericEditField
        PostPatchStateDropDownLabel     matlab.ui.control.Label
        PostPatchStateDropDown          matlab.ui.control.DropDown
        NucleussuckedinDropDownLabel    matlab.ui.control.Label
        NucleussuckedinDropDown         matlab.ui.control.DropDown
        PatchedCellContainerEditFieldLabel  matlab.ui.control.Label
        PatchedCellContainerEditField   matlab.ui.control.EditField
        AdditionalTab                   matlab.ui.container.Tab
        CellAssessmentPanel             matlab.ui.container.Panel
        FluorescenceinPipetteCheckBox   matlab.ui.control.CheckBox
        CellDimmedCheckBox              matlab.ui.control.CheckBox
        CellSwelledCheckBox             matlab.ui.control.CheckBox
        CellShrunkCheckBox              matlab.ui.control.CheckBox
        TooDeepCheckBox                 matlab.ui.control.CheckBox
        CellDisappearedCheckBox         matlab.ui.control.CheckBox
        ExtraExtractionNotesTextAreaLabel  matlab.ui.control.Label
        ExtraExtractionNotesTextArea    matlab.ui.control.TextArea
        SampleAssessmentPanel           matlab.ui.container.Panel
        NoBubblesCheckBox               matlab.ui.control.CheckBox
        SmallBubblesCheckBox            matlab.ui.control.CheckBox
        MediumBubblesCheckBox           matlab.ui.control.CheckBox
        LargeBubblesCheckBox            matlab.ui.control.CheckBox
        SolutioninPipetteShankCheckBox  matlab.ui.control.CheckBox
        FailureTab                      matlab.ui.container.Tab
        CausesofFailurePanel            matlab.ui.container.Panel
        SealFailedCheckBox              matlab.ui.control.CheckBox
        UnstableSealCheckBox            matlab.ui.control.CheckBox
        BreakinFailedCheckBox           matlab.ui.control.CheckBox
        AccessResistanceOutofRangeCheckBox  matlab.ui.control.CheckBox
        VrestOutofRangeCheckBox         matlab.ui.control.CheckBox
        WaveofDeathCheckBox_2           matlab.ui.control.CheckBox
        OtherCheckBox                   matlab.ui.control.CheckBox
        ExtraFailureNotesTextAreaLabel  matlab.ui.control.Label
        ExtraFailureNotesTextArea       matlab.ui.control.TextArea
        AddPatchSeqLogEntryButton       matlab.ui.control.Button
        MarknewattemptButton            matlab.ui.control.Button
        fornonvisualpatchingLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        diary % PatchClampDiary object
        microscope % MicroscopeController object
        positionReporter
    end
    
    methods (Static, Access = private)
        function tz = getTzChar()
            tz = sprintf('%s', tzoffset(datetime('now', 'TimeZone', 'local')));
            if '-' ~= tz(1)
                tz = strcat('+', tz);
            end
        end
        
        function t = getTimeTzChar()
            t = [datestr(now, 'HH:MM:SS '), DiaryGui.getTzChar()];
        end
        
        function d = getDateChar()
            d = datestr(now, 'YYYY-mm-dd');
        end
        
        function d = getDateTimeTzChar()
            d = [datestr(now, 'YYYY-mm-dd HH:MM:SS '), DiaryGui.getTzChar()];
        end
        
        function displayDiary(analyzedData, ax)
            cla(ax);
            ax.NextPlot = 'add';
            legendEntries = {};
            x = [analyzedData.pcData.TargetX];
            y = [analyzedData.pcData.TargetY];
            if ~isempty(x)
                plot(ax, x, y, 'x', 'color', [0, 0.4470, 0.7410]);
                legendEntries = [legendEntries, {'Target'}];
            end
            hx = [analyzedData.holder.x];
            hy = [analyzedData.holder.y];
            if ~isempty(hx)
                plot(ax, hx, hy, 's', 'color', [0.8500, 0.3250, 0.0980]);
                legendEntries = [legendEntries, {'Holder'}];
            end
            sx = [analyzedData.sampleSide.x];
            sy = [analyzedData.sampleSide.y];
            if ~isempty(sx)
                plot(ax, sx, sy, 'o', 'color', [0.9290, 0.6940, 0.1250]);
                legendEntries = [legendEntries, {'Sample side'}];
            end
            legend(ax, legendEntries{:}, 'Location', 'southoutside', ...
                'Orientation', 'horizontal');
        end
    end
    
    methods (Access = private)
        function jem = fillPatchSeqBasic(app, jem)
            jem.approach.sliceHealth = app.SliceHealthButtonGroup.SelectedObject.Text;
            jem.approach.cellHealth = app.CellHealthButtonGroup.SelectedObject.Text;
            jem.approach.creCell = app.CreStatusButtonGroup.SelectedObject.Text;
            jem.approach.pilotName = app.PilotNameDropDown.Value;
            manualRoi = strcat(app.ROIDropDown.Value, app.LayerDropDown.Value);
            jem.manualRoi = manualRoi;
            jem.recording.humanCellTypePrediction = app.CellTypePredictionforHumanNeuronsDropDown.Value;
        end

        function jem = generatePatchSeqFailure(app)
            jem = app.diary.emptyJemFailureData;
            jem = app.fillPatchSeqBasic(jem);
            failureNotes = {};
            if app.UnstableSealCheckBox.Value
                failureNotes{end+1} = app.UnstableSealCheckBox.Text;
            end
            if app.AccessResistanceOutofRangeCheckBox.Value
                failureNotes{end+1} = app.AccessResistanceOutofRangeCheckBox.Text;
            end
            if app.VrestOutofRangeCheckBox.Value
                failureNotes{end+1} = app.VrestOutofRangeCheckBox.Text;
            end
            if app.WaveofDeathCheckBox_2.Value
                failureNotes{end+1} = app.WaveofDeathCheckBox_2.Text;
            end
            if app.OtherCheckBox.Value
                failureNotes{end+1} = app.OtherCheckBox.Text;
            end
            jem.failureNotes = strjoin(failureNotes, ',');
            jem.freeFailureNotes = strjoin(app.ExtraFailureNotesTextArea.Value, '\n');
        end
        
        function jem = generatePatchSeqSuccess(app)
            jem = app.diary.emptyJemSuccessData;
            jem = app.fillPatchSeqBasic(jem);
            successNotes = {};
            if app.PatchCellUnstableCheckBox.Value
                successNotes{end+1} = app.PatchCellUnstableCheckBox.Text;
            end
            if app.PatchBecameLeakyCheckBox.Value
                successNotes{end+1} = app.PatchBecameLeakyCheckBox.Text;
            end
            if app.AccessResistanceIncreasedCheckBox.Value
                successNotes{end+1} = app.AccessResistanceIncreasedCheckBox.Text;
            end
            if app.CellDepolarizedCheckBox.Value
                successNotes{end+1} = app.CellDepolarizedCheckBox.Text;
            end
            if app.CellHyperpolarizedCheckBox.Value
                successNotes{end+1} = app.CellHyperpolarizedCheckBox.Text;
            end
            if app.BlowoutVoltageOutofRangeCheckBox.Value
                successNotes{end+1} = app.BlowoutVoltageOutofRangeCheckBox.Text;
            end
            if app.RheobaseChangedCheckBox.Value
                successNotes{end+1} = app.RheobaseChangedCheckBox.Text;
            end
            if app.EndedAfterC1CheckBox.Value
                successNotes{end+1} = app.EndedAfterC1CheckBox.Text;
            end
            if app.WaveofDeathCheckBox_3.Value
                successNotes{end+1} = app.WaveofDeathCheckBox_3.Text;
            end
            if app.TrainingPracticeCheckBox.Value
                successNotes{end+1} = app.TrainingPracticeCheckBox.Text;
            end
            if app.RigSoftwareProblemsCheckBox.Value
                successNotes{end+1} = app.RigSoftwareProblemsCheckBox.Text;
            end
            jem.successNotes = strjoin(successNotes, ',');
            jem.qcNotes = strjoin(app.QCNotesTextArea.Value, '\n');
            jem.badSweeps = strjoin(app.BadSweepsTextArea.Value, '\n');
            jem.extraction.pressureApplied = app.ExtractionpressurembarEditField.Value;
            jem.extraction.retractionPressureApplied = app.RetractionpressurembarEditField.Value;
            jem.extraction.timeExtractionStart = app.ExtractionStartEditField.Value;
            jem.extraction.timeExtractionEnd = app.RetractionStartEditField.Value;
            jem.extraction.timeRetractionEnd = app.RetractionEndEditField.Value;
            jem.extraction.postPatch = app.PostPatchStateDropDown.Value;
            jem.extraction.endPipetteR = app.PostPatchPipetteREditField.Value;
            jem.extraction.nucleus = app.NucleussuckedinDropDown.Value;
            jem.extraction.tubeID = app.PatchedCellContainerEditField.Value;
            jem.extraction.extractionNotes = strjoin(app.ExtraExtractionNotesTextArea.Value, '\n');
            extractionObservations = {};
            if app.FluorescenceinPipetteCheckBox.Value
                extractionObservations{end+1} = app.FluorescenceinPipetteCheckBox.Text;
            end
            if app.CellDimmedCheckBox.Value
                extractionObservations{end+1} = app.CellDimmedCheckBox.Text;
            end
            if app.CellSwelledCheckBox.Value
                extractionObservations{end+1} = app.CellSwelledCheckBox.Text;
            end
            if app.CellShrunkCheckBox.Value
                extractionObservations{end+1} = app.CellShrunkCheckBox.Text;
            end
            if app.TooDeepCheckBox.Value
                extractionObservations{end+1} = app.TooDeepCheckBox.Text;
            end
            if app.CellDisappearedCheckBox.Value
                extractionObservations{end+1} = app.CellDisappearedCheckBox.Text;
            end
            jem.extractionObservations = strjoin(extractionObservations, ',');
            sampleObservations = {};
            if app.NoBubblesCheckBox.Value
                sampleObservations{end+1} = app.NoBubblesCheckBox.Text;
            end
            if app.SmallBubblesCheckBox.Value
                sampleObservations{end+1} = app.SmallBubblesCheckBox.Text;
            end
            if app.MediumBubblesCheckBox.Value
                sampleObservations{end+1} = app.MediumBubblesCheckBox.Text;
            end
            if app.LargeBubblesCheckBox.Value
                sampleObservations{end+1} = app.LargeBubblesCheckBox.Text;
            end
            if app.SolutioninPipetteShankCheckBox.Value
                sampleObservations{end+1} = app.SolutioninPipetteShankCheckBox.Text;
            end
            jem.sampleObservations = strjoin(sampleObservations, ',');
        end
        
        function analyzedData = refreshDiaryPlot(app)
            analyzedData = [];
            try
                diaryFpath = app.diary.filepath;
                ax = app.sliceInfoAxes;
                if ~exist(diaryFpath, 'file')
                    cla(ax);
                    app.StatusEditField.Value = 'No diary file is present.';
                    return
                end
                app.StatusEditField.Value = 'Refreshing...';
                analyzedData = PatchClampDiary.analyzeDiaryFile(diaryFpath);
                DiaryGui.displayDiary(analyzedData, ax);
                app.StatusEditField.Value = 'Refreshed diary processing and plotting.';
            catch ex
                errMsg = ['Error while refreshing diary: ', ex.message];
                log4m.getLogger().warn(errMsg);
                app.StatusEditField.Value = errMsg;
            end
        end
    end
    
    methods
        function set.diary(app, value)
            assert(isa(value, 'PatchClampDiary'), 'Value should be a PatchClampDiary object.');
            app.diary = value;
        end
        
        function set.microscope(app, value)
            assert(isa(value, 'MicroscopeController'), 'Value should be a MicroscopeController object.');
            app.microscope = value;
        end
    end

    methods (Access = public)
    
        function initialize(app, diary, microscope, positionReporter)
            app.diary = diary;
            app.microscope = microscope;
            if nargin >= 4
                assert(isa(positionReporter, 'function_handle'), 'positionReporter has to be a function handle.');
                app.positionReporter = positionReporter;
            end
        end
        
        function moveToPosition(app, position)
            assert(isempty(position) || (isvector(position) && numel(position)==4));
            if ~isempty(position)
                app.PatchClampDiaryUIFigure.Position = position;
            end
        end
        
        function closeRequest(app)
            app.PatchClampDiaryUIFigureCloseRequest([]);
        end
    end


    methods (Access = private)

        % Button pushed function: AddLogEntryButton
        function AddLogEntryButtonPushed(app, event)
            jemGeneral = PatchClampDiary.emptyJemGeneralData;
            jemGeneral.formVersion = app.JEMversionEditField.Value;
            jemGeneral.rigOperator = app.ResearcherDropDown.Value;
            jemGeneral.rigNumber = app.RigDropDown.Value;
            jemGeneral.date = app.SliceonrigdatetimeEditField.Value;
            jemGeneral.limsSpecName = app.SliceIDEditField.Value;
            jemGeneral.acsfProductionDate = app.ACSFDateEditField.Value;
            jemGeneral.blankFillDate = app.BlankFillDateEditField.Value;
            jemGeneral.internalFillDate = app.InternalFillDateEditField.Value;
            if app.SliceisflippedCheckBox.Value
                jemGeneral.flipped = 'Yes';
            else
                jemGeneral.flipped = 'No';
            end
            sliceQuality = '';
            if app.GoodCheckBox.Value
                sliceQuality = 'Good';
            end
            if app.DamagedCheckBox.Value
                if isempty(sliceQuality)
                    sliceQuality = 'Damaged';
                else
                    sliceQuality = [sliceQuality, ',Damaged'];
                end
            end
            if app.UnevenThicknessCheckBox.Value
                if isempty(sliceQuality)
                    sliceQuality = 'Uneven Thickness';
                else
                    sliceQuality = [sliceQuality, ',Uneven Thickness'];
                end
            end
            if app.WaveofDeathCheckBox.Value
                if isempty(sliceQuality)
                    sliceQuality = '''Wave of Death''';
                else
                    sliceQuality = [sliceQuality, ',''Wave of Death'''];
                end
            end
            if ~isempty(sliceQuality)
                jemGeneral.sliceQuality = sliceQuality;
            end
            app.diary.logJemGeneral(jemGeneral);
            uialert(app.PatchClampDiaryUIFigure, 'JEM slice info successfully added!', 'Patch Clamp Diary', ...
                'Icon', 'success');
        end

        % Button pushed function: AddPatchSeqLogEntryButton
        function AddPatchSeqLogEntryButtonPushed(app, event)
            if strcmp(app.SuccessFailureTabGroup.SelectedTab.Title, 'Failure')
                jem = app.generatePatchSeqFailure();
                app.diary.logJemFailure(jem);
            else
                jem = app.generatePatchSeqSuccess();
                app.diary.logJemSuccess(jem);
            end
            uialert(app.PatchClampDiaryUIFigure, 'JEM Patch-Seq Pipette info successfully added!', ...
                'Patch Clamp Diary', 'Icon', 'success');
        end

        % Button pushed function: AddholderpositionhereButton
        function AddholderpositionhereButtonPushed(app, event)
            try
                pos = app.microscope.stage.getPosition();
                app.diary.logHolderPosition(pos(1), pos(2), pos(3));
                app.StatusEditField.Value = ['Holder position added: ', num2str(round(pos(1))), ', ', num2str(round(pos(2))), ', ', num2str(round(pos(3)))];
            catch ex
                app.StatusEditField.Value = ['Error while adding holder position: ', ex.message];
            end
        end

        % Button pushed function: AddsamplesidepositionhereButton
        function AddsamplesidepositionhereButtonPushed(app, event)
            try
                pos = app.microscope.stage.getPosition();
                app.diary.logSampleSidePosition(pos(1), pos(2), pos(3));
                app.StatusEditField.Value = ['Sample side position added: ', num2str(round(pos(1))), ', ', num2str(round(pos(2))), ', ', num2str(round(pos(3)))];
            catch ex
                app.StatusEditField.Value = ['Error while adding sample side position: ', ex.message];
            end
        end

        % Button pushed function: FailureButton
        function FailureButtonPushed(app, event)
            app.diary.logManualFailure();
            app.StatusEditField.Value = 'Last attempt manually marked as failure.';
        end

        % Button pushed function: MarkmanualapproachButton
        function MarkmanualapproachButtonPushed(app, event)
            try
                [stageX, stageY, stageZ] = app.microscope.stage.getPosition();
                app.diary.logManualAutopatcherStart(stageX, stageY, stageZ);
                app.StatusEditField.Value = 'Logging blind patch-clamp usage as a new attempt.';
            catch ex
                app.StatusEditField.Value = ['Error while marking manual approach: ', ex.message];
            end
        end

        % Button pushed function: NewDiaryFileButton
        function NewDiaryFileButtonPushed(app, event)
            diaryFpath = app.diary.filepath;
            if ~exist(diaryFpath, 'file')
                uialert(app.PatchClampDiaryUIFigure, ['The log file is not present! Use this function'...
                    ' when you have finished with your current sample!'], ...
                    'Patch Clamp Diary', 'Icon', 'info');
                return
            end
            [cfolder, cfile, cext] = fileparts(diaryFpath);
            [fileName, pathName] = uiputfile('*.log', 'Save current diary file as (.log)', cfolder);
            if pathName == 0
                return
            end
            try
                PatchClampDiary.analyzeDiaryFile(diaryFpath);
            catch ex
                log4m.getLogger().error(['Error while generating and saving diary log: ', ex.message]);
                return
            end
            newLogFilepath = [pathName, fileName];
            movefile(diaryFpath, newLogFilepath);
            [folder, fname, ~] = fileparts(diaryFpath);
            csvFpath = fullfile(folder, [fname, '-extracted.csv']);
            [~, fname, ~] = fileparts(newLogFilepath);
            newCsvFpath = fullfile(pathName, [fname, '-extracted.csv']);
            movefile(csvFpath, newCsvFpath);
            app.diary.filepath = fullfile(pathName, [cfile,cext]);
        end

        % Button pushed function: NowButton
        function NowButtonPushed(app, event)
            app.SliceonrigdatetimeEditField.Value = DiaryGui.getDateTimeTzChar();
        end

        % Button pushed function: NowButton_2
        function NowButton_2Pushed(app, event)
            app.ExtractionStartEditField.Value = DiaryGui.getTimeTzChar();
        end

        % Button pushed function: NowButton_3
        function NowButton_3Pushed(app, event)
            app.RetractionStartEditField.Value = DiaryGui.getTimeTzChar();
        end

        % Button pushed function: NowButton_4
        function NowButton_4Pushed(app, event)
            app.RetractionEndEditField.Value = DiaryGui.getTimeTzChar();
        end

        % Close request function: PatchClampDiaryUIFigure
        function PatchClampDiaryUIFigureCloseRequest(app, event)
            if ~isempty(app.positionReporter)
                app.positionReporter(app.PatchClampDiaryUIFigure.Position);
            end
            delete(app);
        end

        % Button pushed function: PopoutButton
        function PopoutButtonPushed(app, event)
            analyzedData = app.refreshDiaryPlot();
            f = figure;
            ax = axes(f);
            DiaryGui.displayDiary(analyzedData, ax);
            print(f, '-r300', '-dpng', 'PatchClampDiary.png');
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshDiaryPlot();
        end

        % Button pushed function: SuccessButton
        function SuccessButtonPushed(app, event)
            app.diary.logManualSuccess();
            app.StatusEditField.Value = 'Last attempt manually marked as success.';
        end

        % Button pushed function: TodayButton
        function TodayButtonPushed(app, event)
            app.ACSFDateEditField.Value = DiaryGui.getDateChar();
        end

        % Button pushed function: TodayButton_2
        function TodayButton_2Pushed(app, event)
            app.BlankFillDateEditField.Value = DiaryGui.getDateChar();
        end

        % Button pushed function: TodayButton_3
        function TodayButton_3Pushed(app, event)
            app.InternalFillDateEditField.Value = DiaryGui.getDateChar();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatchClampDiaryUIFigure = uifigure;
            app.PatchClampDiaryUIFigure.Name = 'Patch Clamp Diary';
            app.PatchClampDiaryUIFigure.CloseRequestFcn = createCallbackFcn(app, @PatchClampDiaryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.PatchClampDiaryUIFigure);
            app.SliceinfoTab = uitab(app.TabGroup);
            app.SliceinfoTab.Title = 'Slice info';
            app.AddholderpositionhereButton = uibutton(app.SliceinfoTab, 'push');
            app.AddholderpositionhereButton.ButtonPushedFcn = createCallbackFcn(app, @AddholderpositionhereButtonPushed, true);
            app.AddholderpositionhereButton.Text = 'Add holder position here';
            app.AddsamplesidepositionhereButton = uibutton(app.SliceinfoTab, 'push');
            app.AddsamplesidepositionhereButton.ButtonPushedFcn = createCallbackFcn(app, @AddsamplesidepositionhereButtonPushed, true);
            app.AddsamplesidepositionhereButton.Text = 'Add sample side position here';
            app.sliceInfoAxes = uiaxes(app.SliceinfoTab);
            title(app.sliceInfoAxes, 'Slice info')
            xlabel(app.sliceInfoAxes, 'X')
            ylabel(app.sliceInfoAxes, 'Y')
            app.RefreshButton = uibutton(app.SliceinfoTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.NewDiaryFileButton = uibutton(app.SliceinfoTab, 'push');
            app.NewDiaryFileButton.ButtonPushedFcn = createCallbackFcn(app, @NewDiaryFileButtonPushed, true);
            app.NewDiaryFileButton.Text = 'New Diary File';
            app.MarklastattemptasLabel = uilabel(app.SliceinfoTab);
            app.MarklastattemptasLabel.Text = 'Mark last attempt as:';
            app.SuccessButton = uibutton(app.SliceinfoTab, 'push');
            app.SuccessButton.ButtonPushedFcn = createCallbackFcn(app, @SuccessButtonPushed, true);
            app.SuccessButton.Text = 'Success';
            app.FailureButton = uibutton(app.SliceinfoTab, 'push');
            app.FailureButton.ButtonPushedFcn = createCallbackFcn(app, @FailureButtonPushed, true);
            app.FailureButton.Text = 'Failure';
            app.MarkmanualapproachButton = uibutton(app.SliceinfoTab, 'push');
            app.MarkmanualapproachButton.ButtonPushedFcn = createCallbackFcn(app, @MarkmanualapproachButtonPushed, true);
            app.MarkmanualapproachButton.Text = 'Mark manual approach';
            app.StatusEditFieldLabel = uilabel(app.SliceinfoTab);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.SliceinfoTab, 'text');
            app.StatusEditField.Editable = 'off';
            app.PopoutButton = uibutton(app.SliceinfoTab, 'push');
            app.PopoutButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutButtonPushed, true);
            app.PopoutButton.Text = 'Pop out';
            app.JEMsliceinfoTab = uitab(app.TabGroup);
            app.JEMsliceinfoTab.Title = 'JEM slice info';
            app.JEMversionEditFieldLabel = uilabel(app.JEMsliceinfoTab);
            app.JEMversionEditFieldLabel.Text = 'JEM version:';
            app.JEMversionEditField = uieditfield(app.JEMsliceinfoTab, 'text');
            app.JEMversionEditField.Enable = 'off';
            app.JEMversionEditField.Value = '2.0.3';
            app.ResearcherDropDownLabel = uilabel(app.JEMsliceinfoTab);
            app.ResearcherDropDownLabel.Enable = 'off';
            app.ResearcherDropDownLabel.Text = 'Researcher';
            app.ResearcherDropDown = uidropdown(app.JEMsliceinfoTab);
            app.ResearcherDropDown.Items = {'autopatcher'};
            app.ResearcherDropDown.Enable = 'off';
            app.ResearcherDropDown.Value = 'autopatcher';
            app.RigDropDownLabel = uilabel(app.JEMsliceinfoTab);
            app.RigDropDownLabel.Enable = 'off';
            app.RigDropDownLabel.Text = 'Rig #';
            app.RigDropDown = uidropdown(app.JEMsliceinfoTab);
            app.RigDropDown.Items = {'tg3'};
            app.RigDropDown.Enable = 'off';
            app.RigDropDown.Value = 'tg3';
            app.SliceonrigdatetimeEditFieldLabel = uilabel(app.JEMsliceinfoTab);
            app.SliceonrigdatetimeEditFieldLabel.Text = 'Slice on rig date/time';
            app.SliceonrigdatetimeEditField = uieditfield(app.JEMsliceinfoTab, 'text');
            app.NowButton = uibutton(app.JEMsliceinfoTab, 'push');
            app.NowButton.ButtonPushedFcn = createCallbackFcn(app, @NowButtonPushed, true);
            app.NowButton.Text = 'Now';
            app.SliceIDEditFieldLabel = uilabel(app.JEMsliceinfoTab);
            app.SliceIDEditFieldLabel.Text = 'Slice ID';
            app.SliceIDEditField = uieditfield(app.JEMsliceinfoTab, 'text');
            app.ACSFDateEditFieldLabel = uilabel(app.JEMsliceinfoTab);
            app.ACSFDateEditFieldLabel.Text = 'ACSF Date';
            app.ACSFDateEditField = uieditfield(app.JEMsliceinfoTab, 'text');
            app.BlankFillDateEditFieldLabel = uilabel(app.JEMsliceinfoTab);
            app.BlankFillDateEditFieldLabel.Text = 'Blank Fill Date';
            app.BlankFillDateEditField = uieditfield(app.JEMsliceinfoTab, 'text');
            app.InternalFillDateEditFieldLabel = uilabel(app.JEMsliceinfoTab);
            app.InternalFillDateEditFieldLabel.Text = 'Internal Fill Date';
            app.InternalFillDateEditField = uieditfield(app.JEMsliceinfoTab, 'text');
            app.TodayButton = uibutton(app.JEMsliceinfoTab, 'push');
            app.TodayButton.ButtonPushedFcn = createCallbackFcn(app, @TodayButtonPushed, true);
            app.TodayButton.Text = 'Today';
            app.TodayButton_2 = uibutton(app.JEMsliceinfoTab, 'push');
            app.TodayButton_2.ButtonPushedFcn = createCallbackFcn(app, @TodayButton_2Pushed, true);
            app.TodayButton_2.Text = 'Today';
            app.TodayButton_3 = uibutton(app.JEMsliceinfoTab, 'push');
            app.TodayButton_3.ButtonPushedFcn = createCallbackFcn(app, @TodayButton_3Pushed, true);
            app.TodayButton_3.Text = 'Today';
            app.SliceisflippedCheckBox = uicheckbox(app.JEMsliceinfoTab);
            app.SliceisflippedCheckBox.Text = 'Slice is flipped';
            app.SliceQualityPanel = uipanel(app.JEMsliceinfoTab);
            app.SliceQualityPanel.Title = 'Slice Quality';
            app.GoodCheckBox = uicheckbox(app.SliceQualityPanel);
            app.GoodCheckBox.Text = 'Good';
            app.DamagedCheckBox = uicheckbox(app.SliceQualityPanel);
            app.DamagedCheckBox.Text = 'Damaged';
            app.UnevenThicknessCheckBox = uicheckbox(app.SliceQualityPanel);
            app.UnevenThicknessCheckBox.Text = 'Uneven Thickness';
            app.WaveofDeathCheckBox = uicheckbox(app.SliceQualityPanel);
            app.WaveofDeathCheckBox.Text = '''Wave of Death''';
            app.ExtraSliceNotesTextAreaLabel = uilabel(app.JEMsliceinfoTab);
            app.ExtraSliceNotesTextAreaLabel.Text = 'Extra Slice Notes';
            app.ExtraSliceNotesTextArea = uitextarea(app.JEMsliceinfoTab);
            app.AddLogEntryButton = uibutton(app.JEMsliceinfoTab, 'push');
            app.AddLogEntryButton.ButtonPushedFcn = createCallbackFcn(app, @AddLogEntryButtonPushed, true);
            app.AddLogEntryButton.Text = 'Add Log Entry';
            app.PatchSeqPipetteTab = uitab(app.TabGroup);
            app.PatchSeqPipetteTab.Title = 'Patch-Seq Pipette';
            app.ApproachInfoPanel = uipanel(app.PatchSeqPipetteTab);
            app.ApproachInfoPanel.Title = 'Approach Info';
            app.SliceHealthButtonGroup = uibuttongroup(app.ApproachInfoPanel);
            app.SliceHealthButtonGroup.Title = 'Slice Health';
            app.Button = uiradiobutton(app.SliceHealthButtonGroup);
            app.Button.Text = '1';
            app.Button_2 = uiradiobutton(app.SliceHealthButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.SliceHealthButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.SliceHealthButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uiradiobutton(app.SliceHealthButtonGroup);
            app.Button_5.Text = '5';
            app.Button_5.Value = true;
            app.CellHealthButtonGroup = uibuttongroup(app.ApproachInfoPanel);
            app.CellHealthButtonGroup.Title = 'Cell Health';
            app.Button_6 = uiradiobutton(app.CellHealthButtonGroup);
            app.Button_6.Text = '1';
            app.Button_7 = uiradiobutton(app.CellHealthButtonGroup);
            app.Button_7.Text = '2';
            app.Button_8 = uiradiobutton(app.CellHealthButtonGroup);
            app.Button_8.Text = '3';
            app.Button_9 = uiradiobutton(app.CellHealthButtonGroup);
            app.Button_9.Text = '4';
            app.Button_10 = uiradiobutton(app.CellHealthButtonGroup);
            app.Button_10.Text = '5';
            app.Button_10.Value = true;
            app.CreStatusButtonGroup = uibuttongroup(app.ApproachInfoPanel);
            app.CreStatusButtonGroup.Title = 'Cre Status';
            app.CreButton = uiradiobutton(app.CreStatusButtonGroup);
            app.CreButton.Text = 'Cre +';
            app.CreButton_2 = uiradiobutton(app.CreStatusButtonGroup);
            app.CreButton_2.Text = 'Cre-';
            app.NoneButton = uiradiobutton(app.CreStatusButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.PilotNameDropDownLabel = uilabel(app.ApproachInfoPanel);
            app.PilotNameDropDownLabel.Text = 'Pilot Name';
            app.PilotNameDropDown = uidropdown(app.ApproachInfoPanel);
            app.PilotNameDropDown.Items = {'None', 'Tissue_Touch', 'Electroporation', 'Non_Human_Primate_LGN', 'Nucleated Patch - Retraction Pressure'};
            app.PilotNameDropDown.Value = 'None';
            app.CellDepthEditFieldLabel = uilabel(app.PatchSeqPipetteTab);
            app.CellDepthEditFieldLabel.Enable = 'off';
            app.CellDepthEditFieldLabel.Text = 'Cell Depth';
            app.CellDepthEditField = uieditfield(app.PatchSeqPipetteTab, 'text');
            app.CellDepthEditField.Enable = 'off';
            app.CellDepthEditField.Value = 'auto';
            app.ROIDropDownLabel = uilabel(app.PatchSeqPipetteTab);
            app.ROIDropDownLabel.Text = 'ROI';
            app.ROIDropDown = uidropdown(app.PatchSeqPipetteTab);
            app.ROIDropDown.Items = {'FCx', 'TCx'};
            app.ROIDropDown.Value = 'FCx';
            app.LayerDropDown = uidropdown(app.PatchSeqPipetteTab);
            app.LayerDropDown.Items = {'', '1', '2', '3', '4', '5', '6'};
            app.LayerDropDown.Value = '';
            app.ResetButton = uibutton(app.PatchSeqPipetteTab, 'push');
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.RecordingInfoPanel = uipanel(app.PatchSeqPipetteTab);
            app.RecordingInfoPanel.Title = 'Recording Info';
            app.ApproachStartEditFieldLabel = uilabel(app.RecordingInfoPanel);
            app.ApproachStartEditFieldLabel.Text = 'Approach Start';
            app.ApproachStartEditField = uieditfield(app.RecordingInfoPanel, 'text');
            app.ApproachStartEditField.Enable = 'off';
            app.ApproachStartEditField.Value = 'auto';
            app.PipetteRMOhmEditFieldLabel = uilabel(app.RecordingInfoPanel);
            app.PipetteRMOhmEditFieldLabel.Text = 'Pipette R (MOhm)';
            app.PipetteRMOhmEditField = uieditfield(app.RecordingInfoPanel, 'text');
            app.PipetteRMOhmEditField.Enable = 'off';
            app.PipetteRMOhmEditField.Value = 'auto';
            app.WholeCellStartEditFieldLabel = uilabel(app.RecordingInfoPanel);
            app.WholeCellStartEditFieldLabel.Text = 'Whole Cell Start';
            app.WholeCellStartEditField = uieditfield(app.RecordingInfoPanel, 'text');
            app.WholeCellStartEditField.Enable = 'off';
            app.WholeCellStartEditField.Value = 'auto';
            app.CellTypePredictionforHumanNeuronsDropDownLabel = uilabel(app.RecordingInfoPanel);
            app.CellTypePredictionforHumanNeuronsDropDownLabel.Text = 'Cell Type Prediction (for Human Neurons)';
            app.CellTypePredictionforHumanNeuronsDropDown = uidropdown(app.RecordingInfoPanel);
            app.CellTypePredictionforHumanNeuronsDropDown.Items = {'None', 'FS Interneuron', 'Pyramidal', 'Spindle Shaped', 'Unknown', 'Unknown Interneuron'};
            app.CellTypePredictionforHumanNeuronsDropDown.Value = 'None';
            app.SuccessFailureTabGroup = uitabgroup(app.PatchSeqPipetteTab);
            app.SuccessTab = uitab(app.SuccessFailureTabGroup);
            app.SuccessTab.Title = 'Success';
            app.TabGroup3 = uitabgroup(app.SuccessTab);
            app.RecordingTab = uitab(app.TabGroup3);
            app.RecordingTab.Title = 'Recording';
            app.RecordingObservationsPanel = uipanel(app.RecordingTab);
            app.RecordingObservationsPanel.Title = 'Recording Observations';
            app.PatchCellUnstableCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.PatchCellUnstableCheckBox.Text = 'Patch/Cell Unstable';
            app.PatchBecameLeakyCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.PatchBecameLeakyCheckBox.Text = 'Patch Became Leaky';
            app.AccessResistanceIncreasedCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.AccessResistanceIncreasedCheckBox.Text = 'Access Resistance Increased';
            app.CellDepolarizedCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.CellDepolarizedCheckBox.Text = 'Cell Depolarized';
            app.CellHyperpolarizedCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.CellHyperpolarizedCheckBox.Text = 'Cell Hyperpolarized';
            app.BlowoutVoltageOutofRangeCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.BlowoutVoltageOutofRangeCheckBox.Text = 'Blowout Voltage Out of Range';
            app.RheobaseChangedCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.RheobaseChangedCheckBox.Text = 'Rheobase Changed';
            app.EndedAfterC1CheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.EndedAfterC1CheckBox.Text = 'Ended After C1';
            app.WaveofDeathCheckBox_3 = uicheckbox(app.RecordingObservationsPanel);
            app.WaveofDeathCheckBox_3.Text = '''Wave of Death''';
            app.TrainingPracticeCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.TrainingPracticeCheckBox.Text = 'Training/Practice';
            app.RigSoftwareProblemsCheckBox = uicheckbox(app.RecordingObservationsPanel);
            app.RigSoftwareProblemsCheckBox.Text = 'Rig/Software Problems';
            app.QCNotesTextAreaLabel = uilabel(app.RecordingTab);
            app.QCNotesTextAreaLabel.Text = 'QC Notes';
            app.QCNotesTextArea = uitextarea(app.RecordingTab);
            app.BadSweepsTextAreaLabel = uilabel(app.RecordingTab);
            app.BadSweepsTextAreaLabel.Text = 'Bad Sweeps';
            app.BadSweepsTextArea = uitextarea(app.RecordingTab);
            app.ExtractionTab = uitab(app.TabGroup3);
            app.ExtractionTab.Title = 'Extraction';
            app.ExtractionpressurembarEditFieldLabel = uilabel(app.ExtractionTab);
            app.ExtractionpressurembarEditFieldLabel.Text = 'Extraction pressure (mbar)';
            app.ExtractionpressurembarEditField = uieditfield(app.ExtractionTab, 'numeric');
            app.ExtractionpressurembarEditField.Limits = [-Inf 0];
            app.RetractionpressurembarEditFieldLabel = uilabel(app.ExtractionTab);
            app.RetractionpressurembarEditFieldLabel.Text = 'Retraction pressure (mbar)';
            app.RetractionpressurembarEditField = uieditfield(app.ExtractionTab, 'numeric');
            app.RetractionpressurembarEditField.Limits = [-Inf 0];
            app.ExtractionStartEditFieldLabel = uilabel(app.ExtractionTab);
            app.ExtractionStartEditFieldLabel.Text = 'Extraction Start';
            app.ExtractionStartEditField = uieditfield(app.ExtractionTab, 'text');
            app.NowButton_2 = uibutton(app.ExtractionTab, 'push');
            app.NowButton_2.ButtonPushedFcn = createCallbackFcn(app, @NowButton_2Pushed, true);
            app.NowButton_2.Text = 'Now';
            app.NowButton_3 = uibutton(app.ExtractionTab, 'push');
            app.NowButton_3.ButtonPushedFcn = createCallbackFcn(app, @NowButton_3Pushed, true);
            app.NowButton_3.Text = 'Now';
            app.RetractionStartEditFieldLabel = uilabel(app.ExtractionTab);
            app.RetractionStartEditFieldLabel.Text = 'Retraction Start';
            app.RetractionStartEditField = uieditfield(app.ExtractionTab, 'text');
            app.NowButton_4 = uibutton(app.ExtractionTab, 'push');
            app.NowButton_4.ButtonPushedFcn = createCallbackFcn(app, @NowButton_4Pushed, true);
            app.NowButton_4.Text = 'Now';
            app.RetractionEndEditFieldLabel = uilabel(app.ExtractionTab);
            app.RetractionEndEditFieldLabel.Text = 'Retraction End';
            app.RetractionEndEditField = uieditfield(app.ExtractionTab, 'text');
            app.PostPatchPipetteREditFieldLabel = uilabel(app.ExtractionTab);
            app.PostPatchPipetteREditFieldLabel.Text = 'Post Patch Pipette R';
            app.PostPatchPipetteREditField = uieditfield(app.ExtractionTab, 'numeric');
            app.PostPatchPipetteREditField.Limits = [0 Inf];
            app.PostPatchStateDropDownLabel = uilabel(app.ExtractionTab);
            app.PostPatchStateDropDownLabel.Text = 'Post Patch State';
            app.PostPatchStateDropDown = uidropdown(app.ExtractionTab);
            app.PostPatchStateDropDown.Items = {'', 'nucleus_present', 'nucleus_absent', 'entire_cell'};
            app.PostPatchStateDropDown.Value = '';
            app.NucleussuckedinDropDownLabel = uilabel(app.ExtractionTab);
            app.NucleussuckedinDropDownLabel.Text = 'Nucleus sucked in?';
            app.NucleussuckedinDropDown = uidropdown(app.ExtractionTab);
            app.NucleussuckedinDropDown.Items = {'', 'intentionally', 'not_intentionally', 'no'};
            app.NucleussuckedinDropDown.Value = '';
            app.PatchedCellContainerEditFieldLabel = uilabel(app.ExtractionTab);
            app.PatchedCellContainerEditFieldLabel.Text = 'Patched Cell Container';
            app.PatchedCellContainerEditField = uieditfield(app.ExtractionTab, 'text');
            app.AdditionalTab = uitab(app.TabGroup3);
            app.AdditionalTab.Title = 'Additional';
            app.CellAssessmentPanel = uipanel(app.AdditionalTab);
            app.CellAssessmentPanel.Title = 'Cell Assessment';
            app.FluorescenceinPipetteCheckBox = uicheckbox(app.CellAssessmentPanel);
            app.FluorescenceinPipetteCheckBox.Text = 'Fluorescence in Pipette';
            app.CellDimmedCheckBox = uicheckbox(app.CellAssessmentPanel);
            app.CellDimmedCheckBox.Text = 'Cell Dimmed';
            app.CellSwelledCheckBox = uicheckbox(app.CellAssessmentPanel);
            app.CellSwelledCheckBox.Text = 'Cell Swelled';
            app.CellShrunkCheckBox = uicheckbox(app.CellAssessmentPanel);
            app.CellShrunkCheckBox.Text = 'Cell Shrunk';
            app.TooDeepCheckBox = uicheckbox(app.CellAssessmentPanel);
            app.TooDeepCheckBox.Text = 'Too Deep';
            app.CellDisappearedCheckBox = uicheckbox(app.CellAssessmentPanel);
            app.CellDisappearedCheckBox.Text = 'Cell Disappeared';
            app.ExtraExtractionNotesTextAreaLabel = uilabel(app.AdditionalTab);
            app.ExtraExtractionNotesTextAreaLabel.Text = 'Extra Extraction Notes';
            app.ExtraExtractionNotesTextArea = uitextarea(app.AdditionalTab);
            app.SampleAssessmentPanel = uipanel(app.AdditionalTab);
            app.SampleAssessmentPanel.Title = 'Sample Assessment';
            app.NoBubblesCheckBox = uicheckbox(app.SampleAssessmentPanel);
            app.NoBubblesCheckBox.Text = 'No Bubbles';
            app.SmallBubblesCheckBox = uicheckbox(app.SampleAssessmentPanel);
            app.SmallBubblesCheckBox.Text = 'Small Bubbles';
            app.MediumBubblesCheckBox = uicheckbox(app.SampleAssessmentPanel);
            app.MediumBubblesCheckBox.Text = 'Medium Bubbles';
            app.LargeBubblesCheckBox = uicheckbox(app.SampleAssessmentPanel);
            app.LargeBubblesCheckBox.Text = 'Large Bubbles';
            app.SolutioninPipetteShankCheckBox = uicheckbox(app.SampleAssessmentPanel);
            app.SolutioninPipetteShankCheckBox.Text = 'Solution in Pipette Shank';
            app.FailureTab = uitab(app.SuccessFailureTabGroup);
            app.FailureTab.Title = 'Failure';
            app.CausesofFailurePanel = uipanel(app.FailureTab);
            app.CausesofFailurePanel.Title = 'Cause(s) of Failure';
            app.SealFailedCheckBox = uicheckbox(app.CausesofFailurePanel);
            app.SealFailedCheckBox.Enable = 'off';
            app.SealFailedCheckBox.Text = 'Seal Failed';
            app.UnstableSealCheckBox = uicheckbox(app.CausesofFailurePanel);
            app.UnstableSealCheckBox.Text = 'Unstable Seal';
            app.BreakinFailedCheckBox = uicheckbox(app.CausesofFailurePanel);
            app.BreakinFailedCheckBox.Enable = 'off';
            app.BreakinFailedCheckBox.Text = 'Breakin Failed';
            app.AccessResistanceOutofRangeCheckBox = uicheckbox(app.CausesofFailurePanel);
            app.AccessResistanceOutofRangeCheckBox.Text = 'Access Resistance Out of Range';
            app.VrestOutofRangeCheckBox = uicheckbox(app.CausesofFailurePanel);
            app.VrestOutofRangeCheckBox.Text = 'Vrest Out of Range';
            app.WaveofDeathCheckBox_2 = uicheckbox(app.CausesofFailurePanel);
            app.WaveofDeathCheckBox_2.Text = '''Wave of Death''';
            app.OtherCheckBox = uicheckbox(app.CausesofFailurePanel);
            app.OtherCheckBox.Text = 'Other';
            app.ExtraFailureNotesTextAreaLabel = uilabel(app.FailureTab);
            app.ExtraFailureNotesTextAreaLabel.Text = 'Extra Failure Notes';
            app.ExtraFailureNotesTextArea = uitextarea(app.FailureTab);
            app.AddPatchSeqLogEntryButton = uibutton(app.PatchSeqPipetteTab, 'push');
            app.AddPatchSeqLogEntryButton.ButtonPushedFcn = createCallbackFcn(app, @AddPatchSeqLogEntryButtonPushed, true);
            app.AddPatchSeqLogEntryButton.Text = 'Add Patch-Seq Log Entry';
            app.MarknewattemptButton = uibutton(app.PatchSeqPipetteTab, 'push');
            app.MarknewattemptButton.Enable = 'off';
            app.MarknewattemptButton.Text = 'Mark new attempt';
            app.fornonvisualpatchingLabel = uilabel(app.PatchSeqPipetteTab);
            app.fornonvisualpatchingLabel.Text = '(for non-visual patching)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DiaryGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatchClampDiaryUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatchClampDiaryUIFigure)
        end
    end
end