classdef PipetteCleaning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DrawbackpositionButton  matlab.ui.control.Button
        AlconoxdishButton       matlab.ui.control.Button
        aCSFdishButton          matlab.ui.control.Button
        CleanpipetteButton      matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        EditField_2             matlab.ui.control.EditField
        EditField_3             matlab.ui.control.EditField
    end


    properties % (Access = private)
        autopatcher
        drawbackPosition
        turretCalibrationPosition
        alconoxPosition
        acsfPosition
    end

    methods (Access = public)
    
        function initialize(app, autopatcher)
            assert(isa(autopatcher, 'AutoPatcher'));
            app.autopatcher = autopatcher;
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: AlconoxdishButton
        function AlconoxdishButtonPushed(app, event)
            pip = app.autopatcher.microscope.getPipette(app.autopatcher.activePipetteId);
            app.alconoxPosition = pip.getPosition();
            app.turretCalibrationPosition = app.autopatcher.microscope.stage.getPosition();
        end

        % Button pushed function: CleanpipetteButton
        function CleanpipetteButtonPushed(app, event)
            pip = app.autopatcher.microscope.getPipette(app.autopatcher.activePipetteId);
            originalPosition = pip.getPosition();
            turretPosition = app.autopatcher.microscope.stage.getPosition();
            
            pip.moveTo(app.drawbackPosition, [], [], 'speed', 'fast');
            pip.waitForFinished();
            adjustedAlconoxPosition = pip.microscope2pipette(turretPosition - app.turretCalibrationPosition, 'relative');
            adjustedAlconoxPosition = app.alconoxPosition + adjustedAlconoxPosition
            pip.moveTo([], adjustedAlconoxPosition(2), adjustedAlconoxPosition(3), 'speed', 'fast');
            pip.waitForFinished();
            pip.moveTo(adjustedAlconoxPosition(1), [], [], 'speed', 'fast');
            pip.waitForFinished();
            
            for i = 1:4
                app.autopatcher.pressureController.setPressure(1000);
                while app.autopatcher.pressureController.getPressure() < 900
                    pause(0.2);
                end
                pause(1);
                app.autopatcher.pressureController.setPressure(0);
                pause(1);
                app.autopatcher.pressureController.setPressure(-300);
                while app.autopatcher.pressureController.getPressure() > -300
                    pause(0.2);
                end
                pause(4);
                app.autopatcher.pressureController.setPressure(0);
                pause(1);
            end
            app.autopatcher.pressureController.setPressure(1000);
            while app.autopatcher.pressureController.getPressure() < 900
                pause(0.2);
            end
            pause(1);
            app.autopatcher.pressureController.setPressure(20);
            pause(1);
            
            pip.moveTo(app.drawbackPosition, [], [], 'speed', 'fast');
            pip.waitForFinished();
            adjustedAcsfPosition = pip.microscope2pipette(turretPosition - app.turretCalibrationPosition, 'relative');
            adjustedAcsfPosition = app.acsfPosition + adjustedAcsfPosition;
            pip.moveTo([], adjustedAcsfPosition(2), adjustedAcsfPosition(3), 'speed', 'fast');
            pip.waitForFinished();
            pip.moveTo(adjustedAcsfPosition(1), [], [], 'speed', 'fast');
            pip.waitForFinished();
            
            app.autopatcher.pressureController.setPressure(1000);
            while app.autopatcher.pressureController.getPressure() < 900
                pause(0.2);
            end
            pause(10);
            app.autopatcher.pressureController.setPressure(0);
            pause(1);
            
            pip.moveTo(app.drawbackPosition, [], [], 'speed', 'fast');
            pip.waitForFinished();
            pip.moveTo([], originalPosition(2), originalPosition(3), 'speed', 'fast');
            pip.waitForFinished();
            pip.moveTo(originalPosition(1), [], [], 'speed', 'fast');
            pip.waitForFinished();


        end

        % Button pushed function: DrawbackpositionButton
        function DrawbackpositionButtonPushed(app, event)
            pip = app.autopatcher.microscope.getPipette(app.autopatcher.activePipetteId);
            app.drawbackPosition = pip.getX();
        end

        % Button pushed function: aCSFdishButton
        function aCSFdishButtonPushed(app, event)
            pip = app.autopatcher.microscope.getPipette(app.autopatcher.activePipetteId);
            app.acsfPosition = pip.getPosition();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DrawbackpositionButton = uibutton(app.UIFigure, 'push');
            app.DrawbackpositionButton.ButtonPushedFcn = createCallbackFcn(app, @DrawbackpositionButtonPushed, true);
            app.DrawbackpositionButton.Text = 'Drawback position';
            app.AlconoxdishButton = uibutton(app.UIFigure, 'push');
            app.AlconoxdishButton.ButtonPushedFcn = createCallbackFcn(app, @AlconoxdishButtonPushed, true);
            app.AlconoxdishButton.Text = 'Alconox dish';
            app.aCSFdishButton = uibutton(app.UIFigure, 'push');
            app.aCSFdishButton.ButtonPushedFcn = createCallbackFcn(app, @aCSFdishButtonPushed, true);
            app.aCSFdishButton.Text = 'aCSF dish';
            app.CleanpipetteButton = uibutton(app.UIFigure, 'push');
            app.CleanpipetteButton.ButtonPushedFcn = createCallbackFcn(app, @CleanpipetteButtonPushed, true);
            app.CleanpipetteButton.Text = 'Clean pipette';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PipetteCleaning

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end