classdef VisualPatcherControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualPatcherControlUIFigure  matlab.ui.Figure
        TargetdistanceLabel           matlab.ui.control.Label
        distanceLabel                 matlab.ui.control.Label
        DodgephaseLabel               matlab.ui.control.Label
        DodgestepLabel                matlab.ui.control.Label
        dodgePhaseLabel               matlab.ui.control.Label
        dodgeStepLabel                matlab.ui.control.Label
        OriginalZpositionLabel        matlab.ui.control.Label
        DistancefromoriginalpositionLabel  matlab.ui.control.Label
        origZLabel                    matlab.ui.control.Label
        totalDistLabel                matlab.ui.control.Label
        umLabel                       matlab.ui.control.Label
        umLabel_2                     matlab.ui.control.Label
        umLabel_3                     matlab.ui.control.Label
        StopVisualPatcherButton       matlab.ui.control.Button
        StatusTextAreaLabel           matlab.ui.control.Label
        statusTextArea                matlab.ui.control.TextArea
        OptionsButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        visualPatcher % VisualPatcher object
        optionsApp
        
        statusMessageListener
        targetDistanceListener
        dodgePhaseListener
        dodgeStepListener
        origZListener
        totalDistListener
        positionReporter
    end

    methods (Access = public)
    
        function initialize(app, visualPatcher, positionReporter)
            assert(isa(visualPatcher, 'VisualPatcher'));
            app.visualPatcher = visualPatcher;
            app.statusTextArea.Value = visualPatcher.statusMessage;
            app.distanceLabel.Text = sprintf('%.2f', visualPatcher.targetDistance);
            app.dodgePhaseLabel.Text = num2str(visualPatcher.dodgePhase);
            app.dodgeStepLabel.Text = num2str(visualPatcher.dodgeStep);
            app.origZLabel.Text = sprintf('%.2f', visualPatcher.tracker.originalZPosition);
            app.totalDistLabel.Text = sprintf('%.2f', visualPatcher.tracker.distanceFromOriginalPosition);
            
            app.statusMessageListener = visualPatcher.addlistener('statusMessage', 'PostSet', ...
                @(src, event) app.statusMessageChangeCb(event));
            app.targetDistanceListener = visualPatcher.addlistener('targetDistance', 'PostSet', ...
                @(src, event) app.targetDistanceChangeCb(event));
            app.dodgePhaseListener = visualPatcher.addlistener('dodgePhase', 'PostSet', ...
                @(src, event) app.dodgePhaseChangeCb(event));
            app.dodgeStepListener = visualPatcher.addlistener('dodgeStep', 'PostSet', ...
                @(src, event) app.dodgeStepChangeCb(event));
            app.totalDistListener = visualPatcher.tracker.addlistener('distanceFromOriginalPosition', 'PostSet', ...
                @(src, event) app.distanceFromOriginalPositionChangeCb(event));
            if nargin >= 3
                assert(isa(positionReporter, 'function_handle'), 'positionReporter has to be a function handle.');
                app.positionReporter = positionReporter;
            end
        end
        
        function moveToPosition(app, position)
            assert(isempty(position) || (isvector(position) && numel(position)==4));
            if ~isempty(position)
                app.VisualPatcherControlUIFigure.Position = position;
            end
        end
        
        function closeRequest(app)
            app.VisualPatcherControlUIFigureCloseRequest([]);
        end
        
    end

    methods (Access = private)
    
        function statusMessageChangeCb(app, ~)
            app.statusTextArea.Value = app.visualPatcher.statusMessage;
            app.origZLabel.Text = sprintf('%.2f', app.visualPatcher.tracker.originalZPosition);
        end
        
        function targetDistanceChangeCb(app, ~)
            app.distanceLabel.Text = sprintf('%.2f', app.visualPatcher.targetDistance);
        end
        
        function dodgePhaseChangeCb(app, ~)
            app.dodgePhaseLabel.Text = num2str(app.visualPatcher.dodgePhase);
        end
        
        function dodgeStepChangeCb(app, ~)
            app.dodgeStepLabel.Text = num2str(app.visualPatcher.dodgeStep);
        end
        
        function distanceFromOriginalPositionChangeCb(app, ~)
            app.totalDistLabel.Text = sprintf('%.2f', app.visualPatcher.tracker.distanceFromOriginalPosition);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.statusTextArea.Value = '';
            app.distanceLabel.Text = '';
            app.dodgePhaseLabel.Text = '';
            app.dodgeStepLabel.Text = '';
            app.distanceLabel.Text = '';
            app.origZLabel.Text = '';
            app.totalDistLabel.Text = '';
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            vpo = app.optionsApp;
            if isempty(vpo) || ~isvalid(vpo)
                vpo = VisualPatcherOptions();
                vpo.initialize(app.visualPatcher);
                app.optionsApp = vpo;
            else
                % this makes the whole X crash on linux, although it works somewhere else
            %                 vpo.VisualPatcherOptionsUIFigure.Visible = 'off';
            %                 vpo.VisualPatcherOptionsUIFigure.Visible = 'on';
                disp('The Visual Patcher Options window is already open, but could not be brought to front.');
            end
        end

        % Button pushed function: StopVisualPatcherButton
        function StopVisualPatcherButtonPushed(app, event)
            app.visualPatcher.stop();
        end

        % Close request function: VisualPatcherControlUIFigure
        function VisualPatcherControlUIFigureCloseRequest(app, event)
            delete(app.optionsApp);
            delete(app.statusMessageListener);
            delete(app.targetDistanceListener);
            delete(app.dodgePhaseListener);
            delete(app.dodgeStepListener);
            delete(app.origZListener);
            delete(app.totalDistListener);
            if ~isempty(app.positionReporter)
                app.positionReporter(app.VisualPatcherControlUIFigure.Position);
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualPatcherControlUIFigure = uifigure;
            app.VisualPatcherControlUIFigure.Name = 'Visual Patcher Control';
            app.VisualPatcherControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @VisualPatcherControlUIFigureCloseRequest, true);
            app.TargetdistanceLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.TargetdistanceLabel.Text = 'Target distance:';
            app.distanceLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.distanceLabel.Text = 'distance';
            app.DodgephaseLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.DodgephaseLabel.Text = 'Dodge phase: ';
            app.DodgestepLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.DodgestepLabel.Text = 'Dodge step: ';
            app.dodgePhaseLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.dodgePhaseLabel.Text = 'phase';
            app.dodgeStepLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.dodgeStepLabel.Text = 'step';
            app.OriginalZpositionLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.OriginalZpositionLabel.Text = 'Original Z position:';
            app.DistancefromoriginalpositionLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.DistancefromoriginalpositionLabel.Text = {'Distance from '; 'original position:'};
            app.origZLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.origZLabel.Text = 'origZ';
            app.totalDistLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.totalDistLabel.Text = 'totalDist';
            app.umLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.umLabel.Text = 'um';
            app.umLabel_2 = uilabel(app.VisualPatcherControlUIFigure);
            app.umLabel_2.Text = 'um';
            app.umLabel_3 = uilabel(app.VisualPatcherControlUIFigure);
            app.umLabel_3.Text = 'um';
            app.StopVisualPatcherButton = uibutton(app.VisualPatcherControlUIFigure, 'push');
            app.StopVisualPatcherButton.ButtonPushedFcn = createCallbackFcn(app, @StopVisualPatcherButtonPushed, true);
            app.StopVisualPatcherButton.Text = 'Stop Visual Patcher';
            app.StatusTextAreaLabel = uilabel(app.VisualPatcherControlUIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.statusTextArea = uitextarea(app.VisualPatcherControlUIFigure);
            app.statusTextArea.Editable = 'off';
            app.OptionsButton = uibutton(app.VisualPatcherControlUIFigure, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Options';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VisualPatcherControl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualPatcherControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualPatcherControlUIFigure)
        end
    end
end