classdef VisualPatcherOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualPatcherOptionsUIFigure   matlab.ui.Figure
        MaincontrolparametersPanel     matlab.ui.container.Panel
        FramerateLabel                 matlab.ui.control.Label
        controlFramerateEditField      matlab.ui.control.NumericEditField
        PipettestepsizeLabel           matlab.ui.control.Label
        pipetteStepsizeEditField       matlab.ui.control.NumericEditField
        StartAutopatcheratdistanceLabel  matlab.ui.control.Label
        startAutopatcheratDistanceEditField  matlab.ui.control.NumericEditField
        AutopatcherpassdistanceEditFieldLabel  matlab.ui.control.Label
        autopatcherPassDistanceEditField  matlab.ui.control.NumericEditField
        StoptrackingatdistanceEditFieldLabel  matlab.ui.control.Label
        stopTrackingAtDistanceEditField  matlab.ui.control.NumericEditField
        ResistancehistorylengthEditFieldLabel  matlab.ui.control.Label
        resistanceHistoryLengthEditField  matlab.ui.control.NumericEditField
        fpsLabel                       matlab.ui.control.Label
        umLabel                        matlab.ui.control.Label
        umLabel_2                      matlab.ui.control.Label
        umLabel_3                      matlab.ui.control.Label
        umLabel_4                      matlab.ui.control.Label
        secLabel                       matlab.ui.control.Label
        ApproachingpressureLabel       matlab.ui.control.Label
        approachingPressureEditField   matlab.ui.control.NumericEditField
        mBarLabel                      matlab.ui.control.Label
        umLabel_10                     matlab.ui.control.Label
        CelloffsetLabel                matlab.ui.control.Label
        CellOffsetXEditField           matlab.ui.control.NumericEditField
        CellOffsetYEditField           matlab.ui.control.NumericEditField
        CellOffsetZEditField           matlab.ui.control.NumericEditField
        TrackingparametersPanel        matlab.ui.container.Panel
        FramerateEditField_2Label      matlab.ui.control.Label
        trackingFramerateEditField     matlab.ui.control.NumericEditField
        ZstacksizeLabel                matlab.ui.control.Label
        zStackSizeEditField            matlab.ui.control.NumericEditField
        ZcorrelationmultiplierLabel    matlab.ui.control.Label
        zCorrelationMultiplierEditField  matlab.ui.control.NumericEditField
        fpsLabel_2                     matlab.ui.control.Label
        umLabel_5                      matlab.ui.control.Label
        pixelLabel                     matlab.ui.control.Label
        CorrectionZstepEditFieldLabel  matlab.ui.control.Label
        correctionZstepEditField       matlab.ui.control.NumericEditField
        TrackingboxradiushalfsizeEditFieldLabel  matlab.ui.control.Label
        trackingBoxRadiusEditField     matlab.ui.control.NumericEditField
        AdjustdistancethresholdEditFieldLabel  matlab.ui.control.Label
        adjustDistanceThresholdEditField  matlab.ui.control.NumericEditField
        umLabel_9                      matlab.ui.control.Label
        ObstacleavoidanceparametersPanel  matlab.ui.container.Panel
        PullbackdistanceLabel          matlab.ui.control.Label
        pullbackDistanceEditField      matlab.ui.control.NumericEditField
        PassobstaclebydistanceLabel    matlab.ui.control.Label
        passObstacleByDistanceEditField  matlab.ui.control.NumericEditField
        deltaRLabel                    matlab.ui.control.Label
        deltaREditField                matlab.ui.control.NumericEditField
        deltaPhiLabel                  matlab.ui.control.Label
        deltaPhiEditField              matlab.ui.control.NumericEditField
        umLabel_6                      matlab.ui.control.Label
        umLabel_7                      matlab.ui.control.Label
        umLabel_8                      matlab.ui.control.Label
        radLabel                       matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
    end


    properties (Access = private)
        visualPatcher % VisualPatcher object
    end

    methods (Access = public)
    
        function initialize(app, visualPatcher)
            assert(isa(visualPatcher, 'VisualPatcher'));
            app.visualPatcher = visualPatcher;
            
            err = 0;
            err = err || app.failsafeSetValue(app.controlFramerateEditField, visualPatcher.controlFrameRate);
            err = err || app.failsafeSetValue(app.pipetteStepsizeEditField, visualPatcher.pipetteStepsize);
            err = err || app.failsafeSetValue(app.startAutopatcheratDistanceEditField, visualPatcher.startAutopatcherAtDistance);
            err = err || app.failsafeSetValue(app.autopatcherPassDistanceEditField, visualPatcher.autopatcherPassDistance);
            err = err || app.failsafeSetValue(app.stopTrackingAtDistanceEditField, visualPatcher.stopTrackingAtDistance);
            err = err || app.failsafeSetValue(app.resistanceHistoryLengthEditField, visualPatcher.rWindowHistoryTime);
            err = err || app.failsafeSetValue(app.approachingPressureEditField, visualPatcher.approachingPressure);
            
            err = err || app.failsafeSetValue(app.correctionZstepEditField, visualPatcher.tracker.zStep);
            err = err || app.failsafeSetValue(app.trackingBoxRadiusEditField, visualPatcher.tracker.radius);
            err = err || app.failsafeSetValue(app.adjustDistanceThresholdEditField, visualPatcher.tracker.distanceThreshold);
            err = err || app.failsafeSetValue(app.trackingFramerateEditField, visualPatcher.tracker.trackerFrameRate);
            err = err || app.failsafeSetValue(app.zStackSizeEditField, visualPatcher.tracker.numZ);
            err = err || app.failsafeSetValue(app.zCorrelationMultiplierEditField, visualPatcher.tracker.corrZsameMultiplier);
            
            err = err || app.failsafeSetValue(app.pullbackDistanceEditField, visualPatcher.dodgePullDistance);
            err = err || app.failsafeSetValue(app.passObstacleByDistanceEditField, visualPatcher.dodgePassDistance);
            err = err || app.failsafeSetValue(app.deltaREditField, visualPatcher.dodgeDeltaR);
            err = err || app.failsafeSetValue(app.deltaPhiEditField, visualPatcher.dodgeDeltaPhi);
            err = err || app.failsafeSetValue(app.CellOffsetXEditField, visualPatcher.cellOffset(1));
            err = err || app.failsafeSetValue(app.CellOffsetYEditField, visualPatcher.cellOffset(2));
            err = err || app.failsafeSetValue(app.CellOffsetZEditField, visualPatcher.cellOffset(3));
            
            if err
                warndlg('Some parameters are outside the limits set in gui and their default values are shown!');
            end
        end
        
    end

    methods (Access = private, Static)
    
        function error = failsafeSetValue(obj, newValue)
        % FAILSAFESETVALUE Failsafe set gui element value.
        %     If an object property could not be set from gui, revert the 
        % displayed value to the current value of property.
            error = 0;
            try
                obj.Value = newValue;
            catch ex
                error = 1;
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: CellOffsetXEditField
        function CellOffsetXEditFieldValueChanged(app, event)
            value = app.CellOffsetXEditField.Value;
            try
                app.visualPatcher.cellOffset(1) = value;
            catch ex
                app.failsafeSetValue(app.CellOffsetXEditField, app.visualPatcher.cellOffset(1));
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: CellOffsetYEditField
        function CellOffsetYEditFieldValueChanged(app, event)
            value = app.CellOffsetYEditField.Value;
            try
                app.visualPatcher.cellOffset(2) = value;
            catch ex
                app.failsafeSetValue(app.CellOffsetYEditField, app.visualPatcher.cellOffset(2));
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: CellOffsetZEditField
        function CellOffsetZEditFieldValueChanged(app, event)
            value = app.CellOffsetZEditField.Value;
            try
                app.visualPatcher.cellOffset(3) = value;
            catch ex
                app.failsafeSetValue(app.CellOffsetZEditField, app.visualPatcher.cellOffset(3));
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.delete();
        end

        % Value changed function: adjustDistanceThresholdEditField
        function adjustDistanceThresholdEditFieldValueChanged(app, event)
            value = app.adjustDistanceThresholdEditField.Value;
            try
                app.visualPatcher.tracker.distanceThreshold = value;
            catch ex
                app.failsafeSetValue(app.adjustDistanceThresholdEditField, app.visualPatcher.tracker.distanceThreshold);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: approachingPressureEditField
        function approachingPressureEditFieldValueChanged(app, event)
            value = app.approachingPressureEditField.Value;
            try
                app.visualPatcher.approachingPressure = value;
            catch ex
                app.failsafeSetValue(app.approachingPressureEditField, app.visualPatcher.approachingPressure);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: autopatcherPassDistanceEditField
        function autopatcherPassDistanceEditFieldValueChanged(app, event)
            value = app.autopatcherPassDistanceEditField.Value;
            try
                app.visualPatcher.autopatcherPassDistance = value;
            catch ex
                app.failsafeSetValue(app.autopatcherPassDistanceEditField, app.visualPatcher.autopatcherPassDistance);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: controlFramerateEditField
        function controlFramerateEditFieldValueChanged(app, event)
            value = app.controlFramerateEditField.Value;
            try
                app.visualPatcher.controlFrameRate = value;
            catch ex
                app.failsafeSetValue(app.controlFramerateEditField, app.visualPatcher.controlFrameRate);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: correctionZstepEditField
        function correctionZstepEditFieldValueChanged(app, event)
            value = app.correctionZstepEditField.Value;
            try
                app.visualPatcher.tracker.zStep = value;
            catch ex
                app.failsafeSetValue(app.correctionZstepEditField, app.visualPatcher.tracker.zStep);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: deltaPhiEditField
        function deltaPhiEditFieldValueChanged(app, event)
            value = app.deltaPhiEditField.Value;
            try
                app.visualPatcher.dodgeDeltaPhi = value;
            catch ex
                app.failsafeSetValue(app.deltaPhiEditField, app.visualPatcher.dodgeDeltaPhi);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: deltaREditField
        function deltaREditFieldValueChanged(app, event)
            value = app.deltaREditField.Value;
            try
                app.visualPatcher.dodgeDeltaR = value;
            catch ex
                app.failsafeSetValue(app.deltaREditField, app.visualPatcher.dodgeDeltaR);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: passObstacleByDistanceEditField
        function passObstacleByDistanceEditFieldValueChanged(app, event)
            value = app.passObstacleByDistanceEditField.Value;
            try
                app.visualPatcher.dodgePassDistance = value;
            catch ex
                app.failsafeSetValue(app.passObstacleByDistanceEditField, app.visualPatcher.dodgePassDistance);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: pipetteStepsizeEditField
        function pipetteStepsizeEditFieldValueChanged(app, event)
            value = app.pipetteStepsizeEditField.Value;
            try
                app.visualPatcher.pipetteStepsize = value;
            catch ex
                app.failsafeSetValue(app.pipetteStepsizeEditField, app.visualPatcher.pipetteStepsize);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: pullbackDistanceEditField
        function pullbackDistanceEditFieldValueChanged(app, event)
            value = app.pullbackDistanceEditField.Value;
            try
                app.visualPatcher.dodgePullDistance = value;
            catch ex
                app.failsafeSetValue(app.pullbackDistanceEditField, app.visualPatcher.dodgePullDistance);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: resistanceHistoryLengthEditField
        function resistanceHistoryLengthEditFieldValueChanged(app, event)
            value = app.resistanceHistoryLengthEditField.Value;
            try
                app.visualPatcher.rWindowHistoryTime = value;
            catch ex
                app.failsafeSetValue(app.resistanceHistoryLengthEditField, app.visualPatcher.rWindowHistoryTime);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: startAutopatcheratDistanceEditField
        function startAutopatcheratDistanceEditFieldValueChanged(app, event)
            value = app.startAutopatcheratDistanceEditField.Value;
            try
                app.visualPatcher.startAutopatcherAtDistance = value;
            catch ex
                app.failsafeSetValue(app.startAutopatcheratDistanceEditField, app.visualPatcher.startAutopatcherAtDistance);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: stopTrackingAtDistanceEditField
        function stopTrackingAtDistanceEditFieldValueChanged(app, event)
            value = app.stopTrackingAtDistanceEditField.Value;
            try
                app.visualPatcher.stopTrackingAtDistance = value;
            catch ex
                app.failsafeSetValue(app.stopTrackingAtDistanceEditField, app.visualPatcher.stopTrackingAtDistance);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: trackingBoxRadiusEditField
        function trackingBoxRadiusEditFieldValueChanged(app, event)
            value = app.trackingBoxRadiusEditField.Value;
            try
                app.visualPatcher.tracker.radius = value;
            catch ex
                app.failsafeSetValue(app.trackingBoxRadiusEditField, app.visualPatcher.tracker.radius);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: trackingFramerateEditField
        function trackingFramerateEditFieldValueChanged(app, event)
            value = app.trackingFramerateEditField.Value;
            try
                app.visualPatcher.tracker.trackerFrameRate = value;
            catch ex
                app.failsafeSetValue(app.trackingFramerateEditField, app.visualPatcher.tracker.trackerFrameRate);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: zCorrelationMultiplierEditField
        function zCorrelationMultiplierEditFieldValueChanged(app, event)
            value = app.zCorrelationMultiplierEditField.Value;
            try
                app.visualPatcher.tracker.corrZsameMultiplier = value;
            catch ex
                app.failsafeSetValue(app.zCorrelationMultiplierEditField, app.visualPatcher.tracker.corrZsameMultiplier);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end

        % Value changed function: zStackSizeEditField
        function zStackSizeEditFieldValueChanged(app, event)
            value = app.zStackSizeEditField.Value;
            try
                app.visualPatcher.tracker.numZ = value;
            catch ex
                app.failsafeSetValue(app.zStackSizeEditField, app.visualPatcher.tracker.numZ);
                warndlg(['Error while setting visual patcher parameter: ', ex.message]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualPatcherOptionsUIFigure = uifigure;
            app.VisualPatcherOptionsUIFigure.Name = 'Visual Patcher Options';
            app.MaincontrolparametersPanel = uipanel(app.VisualPatcherOptionsUIFigure);
            app.MaincontrolparametersPanel.Title = 'Main control parameters';
            app.FramerateLabel = uilabel(app.MaincontrolparametersPanel);
            app.FramerateLabel.Text = 'Frame rate:';
            app.controlFramerateEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.controlFramerateEditField.LowerLimitInclusive = 'off';
            app.controlFramerateEditField.ValueChangedFcn = createCallbackFcn(app, @controlFramerateEditFieldValueChanged, true);
            app.controlFramerateEditField.Limits = [0 100];
            app.controlFramerateEditField.RoundFractionalValues = 'on';
            app.controlFramerateEditField.Value = 1;
            app.PipettestepsizeLabel = uilabel(app.MaincontrolparametersPanel);
            app.PipettestepsizeLabel.Text = 'Pipette step size:';
            app.pipetteStepsizeEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.pipetteStepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @pipetteStepsizeEditFieldValueChanged, true);
            app.pipetteStepsizeEditField.Limits = [0 Inf];
            app.StartAutopatcheratdistanceLabel = uilabel(app.MaincontrolparametersPanel);
            app.StartAutopatcheratdistanceLabel.Text = 'Start Autopatcher at distance:';
            app.startAutopatcheratDistanceEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.startAutopatcheratDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @startAutopatcheratDistanceEditFieldValueChanged, true);
            app.startAutopatcheratDistanceEditField.Limits = [0 Inf];
            app.AutopatcherpassdistanceEditFieldLabel = uilabel(app.MaincontrolparametersPanel);
            app.AutopatcherpassdistanceEditFieldLabel.Text = 'Autopatcher pass distance';
            app.autopatcherPassDistanceEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.autopatcherPassDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @autopatcherPassDistanceEditFieldValueChanged, true);
            app.autopatcherPassDistanceEditField.Limits = [0 Inf];
            app.StoptrackingatdistanceEditFieldLabel = uilabel(app.MaincontrolparametersPanel);
            app.StoptrackingatdistanceEditFieldLabel.Text = 'Stop tracking at distance';
            app.stopTrackingAtDistanceEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.stopTrackingAtDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @stopTrackingAtDistanceEditFieldValueChanged, true);
            app.stopTrackingAtDistanceEditField.Limits = [0 Inf];
            app.ResistancehistorylengthEditFieldLabel = uilabel(app.MaincontrolparametersPanel);
            app.ResistancehistorylengthEditFieldLabel.Text = 'Resistance history length';
            app.resistanceHistoryLengthEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.resistanceHistoryLengthEditField.LowerLimitInclusive = 'off';
            app.resistanceHistoryLengthEditField.ValueChangedFcn = createCallbackFcn(app, @resistanceHistoryLengthEditFieldValueChanged, true);
            app.resistanceHistoryLengthEditField.Limits = [0 Inf];
            app.resistanceHistoryLengthEditField.Value = 1;
            app.fpsLabel = uilabel(app.MaincontrolparametersPanel);
            app.fpsLabel.Text = 'fps';
            app.umLabel = uilabel(app.MaincontrolparametersPanel);
            app.umLabel.Text = 'um';
            app.umLabel_2 = uilabel(app.MaincontrolparametersPanel);
            app.umLabel_2.Text = 'um';
            app.umLabel_3 = uilabel(app.MaincontrolparametersPanel);
            app.umLabel_3.Text = 'um';
            app.umLabel_4 = uilabel(app.MaincontrolparametersPanel);
            app.umLabel_4.Text = 'um';
            app.secLabel = uilabel(app.MaincontrolparametersPanel);
            app.secLabel.Text = 'sec';
            app.ApproachingpressureLabel = uilabel(app.MaincontrolparametersPanel);
            app.ApproachingpressureLabel.Text = 'Approaching pressure:';
            app.approachingPressureEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.approachingPressureEditField.ValueChangedFcn = createCallbackFcn(app, @approachingPressureEditFieldValueChanged, true);
            app.approachingPressureEditField.Limits = [0 Inf];
            app.mBarLabel = uilabel(app.MaincontrolparametersPanel);
            app.mBarLabel.Text = 'mBar';
            app.umLabel_10 = uilabel(app.MaincontrolparametersPanel);
            app.umLabel_10.Text = 'um';
            app.CelloffsetLabel = uilabel(app.MaincontrolparametersPanel);
            app.CelloffsetLabel.Text = 'Cell offset:';
            app.CellOffsetXEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.CellOffsetXEditField.LowerLimitInclusive = 'off';
            app.CellOffsetXEditField.ValueChangedFcn = createCallbackFcn(app, @CellOffsetXEditFieldValueChanged, true);
            app.CellOffsetXEditField.RoundFractionalValues = 'on';
            app.CellOffsetYEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.CellOffsetYEditField.LowerLimitInclusive = 'off';
            app.CellOffsetYEditField.ValueChangedFcn = createCallbackFcn(app, @CellOffsetYEditFieldValueChanged, true);
            app.CellOffsetYEditField.RoundFractionalValues = 'on';
            app.CellOffsetZEditField = uieditfield(app.MaincontrolparametersPanel, 'numeric');
            app.CellOffsetZEditField.LowerLimitInclusive = 'off';
            app.CellOffsetZEditField.ValueChangedFcn = createCallbackFcn(app, @CellOffsetZEditFieldValueChanged, true);
            app.CellOffsetZEditField.RoundFractionalValues = 'on';
            app.TrackingparametersPanel = uipanel(app.VisualPatcherOptionsUIFigure);
            app.TrackingparametersPanel.Title = 'Tracking parameters';
            app.FramerateEditField_2Label = uilabel(app.TrackingparametersPanel);
            app.FramerateEditField_2Label.Text = 'Frame rate:';
            app.trackingFramerateEditField = uieditfield(app.TrackingparametersPanel, 'numeric');
            app.trackingFramerateEditField.LowerLimitInclusive = 'off';
            app.trackingFramerateEditField.ValueChangedFcn = createCallbackFcn(app, @trackingFramerateEditFieldValueChanged, true);
            app.trackingFramerateEditField.Limits = [0 Inf];
            app.trackingFramerateEditField.RoundFractionalValues = 'on';
            app.trackingFramerateEditField.Value = 1;
            app.ZstacksizeLabel = uilabel(app.TrackingparametersPanel);
            app.ZstacksizeLabel.Text = 'Z stack size:';
            app.zStackSizeEditField = uieditfield(app.TrackingparametersPanel, 'numeric');
            app.zStackSizeEditField.ValueChangedFcn = createCallbackFcn(app, @zStackSizeEditFieldValueChanged, true);
            app.zStackSizeEditField.Limits = [3 Inf];
            app.zStackSizeEditField.RoundFractionalValues = 'on';
            app.zStackSizeEditField.ValueDisplayFormat = '%.0f';
            app.zStackSizeEditField.Value = 3;
            app.ZcorrelationmultiplierLabel = uilabel(app.TrackingparametersPanel);
            app.ZcorrelationmultiplierLabel.Text = 'Z correlation multiplier:';
            app.zCorrelationMultiplierEditField = uieditfield(app.TrackingparametersPanel, 'numeric');
            app.zCorrelationMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @zCorrelationMultiplierEditFieldValueChanged, true);
            app.fpsLabel_2 = uilabel(app.TrackingparametersPanel);
            app.fpsLabel_2.Text = 'fps';
            app.umLabel_5 = uilabel(app.TrackingparametersPanel);
            app.umLabel_5.Text = 'um';
            app.pixelLabel = uilabel(app.TrackingparametersPanel);
            app.pixelLabel.Text = 'pixel';
            app.CorrectionZstepEditFieldLabel = uilabel(app.TrackingparametersPanel);
            app.CorrectionZstepEditFieldLabel.Text = 'Correction Z step:';
            app.correctionZstepEditField = uieditfield(app.TrackingparametersPanel, 'numeric');
            app.correctionZstepEditField.ValueChangedFcn = createCallbackFcn(app, @correctionZstepEditFieldValueChanged, true);
            app.correctionZstepEditField.Limits = [0 Inf];
            app.TrackingboxradiushalfsizeEditFieldLabel = uilabel(app.TrackingparametersPanel);
            app.TrackingboxradiushalfsizeEditFieldLabel.Text = 'Tracking box radius (half size):';
            app.trackingBoxRadiusEditField = uieditfield(app.TrackingparametersPanel, 'numeric');
            app.trackingBoxRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @trackingBoxRadiusEditFieldValueChanged, true);
            app.trackingBoxRadiusEditField.Limits = [10 Inf];
            app.trackingBoxRadiusEditField.RoundFractionalValues = 'on';
            app.trackingBoxRadiusEditField.ValueDisplayFormat = '%.0f';
            app.trackingBoxRadiusEditField.Value = 10;
            app.AdjustdistancethresholdEditFieldLabel = uilabel(app.TrackingparametersPanel);
            app.AdjustdistancethresholdEditFieldLabel.Text = 'Adjust distance threshold: ';
            app.adjustDistanceThresholdEditField = uieditfield(app.TrackingparametersPanel, 'numeric');
            app.adjustDistanceThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @adjustDistanceThresholdEditFieldValueChanged, true);
            app.adjustDistanceThresholdEditField.Limits = [0 Inf];
            app.umLabel_9 = uilabel(app.TrackingparametersPanel);
            app.umLabel_9.Text = 'um';
            app.ObstacleavoidanceparametersPanel = uipanel(app.VisualPatcherOptionsUIFigure);
            app.ObstacleavoidanceparametersPanel.Title = 'Obstacle avoidance parameters';
            app.PullbackdistanceLabel = uilabel(app.ObstacleavoidanceparametersPanel);
            app.PullbackdistanceLabel.Text = 'Pull back distance:';
            app.pullbackDistanceEditField = uieditfield(app.ObstacleavoidanceparametersPanel, 'numeric');
            app.pullbackDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @pullbackDistanceEditFieldValueChanged, true);
            app.pullbackDistanceEditField.Limits = [0 Inf];
            app.PassobstaclebydistanceLabel = uilabel(app.ObstacleavoidanceparametersPanel);
            app.PassobstaclebydistanceLabel.Text = 'Pass obstacle by distance:';
            app.passObstacleByDistanceEditField = uieditfield(app.ObstacleavoidanceparametersPanel, 'numeric');
            app.passObstacleByDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @passObstacleByDistanceEditFieldValueChanged, true);
            app.passObstacleByDistanceEditField.Limits = [0 Inf];
            app.deltaRLabel = uilabel(app.ObstacleavoidanceparametersPanel);
            app.deltaRLabel.Text = 'delta R:';
            app.deltaREditField = uieditfield(app.ObstacleavoidanceparametersPanel, 'numeric');
            app.deltaREditField.ValueChangedFcn = createCallbackFcn(app, @deltaREditFieldValueChanged, true);
            app.deltaREditField.Limits = [0 Inf];
            app.deltaPhiLabel = uilabel(app.ObstacleavoidanceparametersPanel);
            app.deltaPhiLabel.Text = 'delta Phi:';
            app.deltaPhiEditField = uieditfield(app.ObstacleavoidanceparametersPanel, 'numeric');
            app.deltaPhiEditField.ValueChangedFcn = createCallbackFcn(app, @deltaPhiEditFieldValueChanged, true);
            app.umLabel_6 = uilabel(app.ObstacleavoidanceparametersPanel);
            app.umLabel_6.Text = 'um';
            app.umLabel_7 = uilabel(app.ObstacleavoidanceparametersPanel);
            app.umLabel_7.Text = 'um';
            app.umLabel_8 = uilabel(app.ObstacleavoidanceparametersPanel);
            app.umLabel_8.Text = 'um';
            app.radLabel = uilabel(app.ObstacleavoidanceparametersPanel);
            app.radLabel.Text = 'rad';
            app.OKButton = uibutton(app.VisualPatcherOptionsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VisualPatcherOptions

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualPatcherOptionsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualPatcherOptionsUIFigure)
        end
    end
end