classdef VisualizationToolOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizationTooloptionsUIFigure  matlab.ui.Figure
        CameratimerperiodLabel        matlab.ui.control.Label
        CameratimerperiodEditField    matlab.ui.control.NumericEditField
        AcquiredstacksizeLabel        matlab.ui.control.Label
        AcquiredstacksizeEditField    matlab.ui.control.NumericEditField
        DICreconstructionPanel        matlab.ui.container.Panel
        IterationsLabel               matlab.ui.control.Label
        IterationsEditField           matlab.ui.control.NumericEditField
        DirectiondegreesLabel         matlab.ui.control.Label
        DirectiondegreesEditField     matlab.ui.control.NumericEditField
        StepsizeweightLabel           matlab.ui.control.Label
        StepsizeweightEditField       matlab.ui.control.NumericEditField
        SmoothnessweightLabel         matlab.ui.control.Label
        SmoothnessweightEditField     matlab.ui.control.NumericEditField
        CoresinathreadEditFieldLabel  matlab.ui.control.Label
        CoresinathreadEditField       matlab.ui.control.NumericEditField
        OKButton                      matlab.ui.control.Button
        SaveFindPatchstackCheckBox    matlab.ui.control.CheckBox
    end


    properties (Access = private)
        generalParameters % GeneralParameters object to operate on
    end

    methods (Static, Access = private)
    
        function error = failsafeSetValue(obj, newValue)
            error = 0;
            try
                obj.Value = newValue;
            catch ex
                error = 1;
            end
        end
        
    end

    methods (Access = public)
    
        function initialize(app, generalParameters)
            assert(isa(generalParameters, 'GeneralParameters'));
            app.generalParameters = generalParameters;
            
            app.CameratimerperiodEditField.Value = app.generalParameters.cameraTimerPeriod;
            app.AcquiredstacksizeEditField.Value = app.generalParameters.stackSize;
            app.SaveFindPatchstackCheckBox.Value = app.generalParameters.logFindAndPatchStack;
            app.IterationsEditField.Value = app.generalParameters.dicIterations;
            app.DirectiondegreesEditField.Value = app.generalParameters.dicDirection;
            app.StepsizeweightEditField.Value = app.generalParameters.dicWAccept;
            app.SmoothnessweightEditField.Value = app.generalParameters.dicWSmooth;
            app.CoresinathreadEditField.Value = app.generalParameters.dicLocsize;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AcquiredstacksizeEditField
        function AcquiredstacksizeEditFieldValueChanged(app, event)
            value = app.AcquiredstacksizeEditField.Value;
            try
                app.generalParameters.stackSize = value;
            catch ex
                app.failsafeSetValue(app.AcquiredstacksizeEditField, app.generalParameters.stackSize);
                uialert(app.VisualizationTooloptionsUIFigure, ['Error while setting parameter: ', ex.message], 'Visualization Tool options', 'Icon', 'error');
            end
        end

        % Value changed function: CameratimerperiodEditField
        function CameratimerperiodEditFieldValueChanged(app, event)
            value = app.CameratimerperiodEditField.Value;
            try
                app.generalParameters.cameraTimerPeriod = value;
            catch ex
                app.failsafeSetValue(app.CameratimerperiodEditField, app.generalParameters.cameraTimerPeriod);
                uialert(app.VisualizationTooloptionsUIFigure, ['Error while setting parameter: ', ex.message], 'Visualization Tool options', 'Icon', 'error');
            end
        end

        % Value changed function: CoresinathreadEditField
        function CoresinathreadEditFieldValueChanged(app, event)
            value = app.CoresinathreadEditField.Value;
            try
                app.generalParameters.dicLocsize = value;
            catch ex
                app.failsafeSetValue(app.CoresinathreadEditField, app.generalParameters.dicLocsize);
                uialert(app.VisualizationTooloptionsUIFigure, ['Error while setting parameter: ', ex.message], 'Visualization Tool options', 'Icon', 'error');
            end
        end

        % Value changed function: DirectiondegreesEditField
        function DirectiondegreesEditFieldValueChanged(app, event)
            value = app.DirectiondegreesEditField.Value;
            try
                app.generalParameters.dicDirection = value;
            catch ex
                app.failsafeSetValue(app.DirectiondegreesEditField, app.generalParameters.dicDirection);
                uialert(app.VisualizationTooloptionsUIFigure, ['Error while setting parameter: ', ex.message], 'Visualization Tool options', 'Icon', 'error');
            end
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            try
                app.generalParameters.dicIterations = value;
            catch ex
                app.failsafeSetValue(app.IterationsEditField, app.generalParameters.dicIterations);
                uialert(app.VisualizationTooloptionsUIFigure, ['Error while setting parameter: ', ex.message], 'Visualization Tool options', 'Icon', 'error');
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.delete();
        end

        % Value changed function: SaveFindPatchstackCheckBox
        function SaveFindPatchstackCheckBoxValueChanged(app, event)
            value = app.SaveFindPatchstackCheckBox.Value;
            app.generalParameters.logFindAndPatchStack = value;
        end

        % Value changed function: SmoothnessweightEditField
        function SmoothnessweightEditFieldValueChanged(app, event)
            value = app.SmoothnessweightEditField.Value;
            try
                app.generalParameters.dicWSmooth = value;
            catch ex
                app.failsafeSetValue(app.SmoothnessweightEditField, app.generalParameters.dicWSmooth);
                uialert(app.VisualizationTooloptionsUIFigure, ['Error while setting parameter: ', ex.message], 'Visualization Tool options', 'Icon', 'error');
            end
        end

        % Value changed function: StepsizeweightEditField
        function StepsizeweightEditFieldValueChanged(app, event)
            value = app.StepsizeweightEditField.Value;
            try
                app.generalParameters.dicWAccept = value;
            catch ex
                app.failsafeSetValue(app.StepsizeweightEditField, app.generalParameters.dicWAccept);
                uialert(app.VisualizationTooloptionsUIFigure, ['Error while setting parameter: ', ex.message], 'Visualization Tool options', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizationTooloptionsUIFigure = uifigure('Visible', 'off');
            app.VisualizationTooloptionsUIFigure.Name = 'Visualization Tool options';
            app.CameratimerperiodLabel = uilabel(app.VisualizationTooloptionsUIFigure);
            app.CameratimerperiodLabel.Text = 'Camera timer period:';
            app.CameratimerperiodEditField = uieditfield(app.VisualizationTooloptionsUIFigure, 'numeric');
            app.CameratimerperiodEditField.Limits = [0 Inf];
            app.CameratimerperiodEditField.ValueChangedFcn = createCallbackFcn(app, @CameratimerperiodEditFieldValueChanged, true);
            app.CameratimerperiodEditField.Value = 1;
            app.AcquiredstacksizeLabel = uilabel(app.VisualizationTooloptionsUIFigure);
            app.AcquiredstacksizeLabel.Text = 'Acquired stack size:';
            app.AcquiredstacksizeEditField = uieditfield(app.VisualizationTooloptionsUIFigure, 'numeric');
            app.AcquiredstacksizeEditField.Limits = [1 Inf];
            app.AcquiredstacksizeEditField.ValueDisplayFormat = '%.0f';
            app.AcquiredstacksizeEditField.ValueChangedFcn = createCallbackFcn(app, @AcquiredstacksizeEditFieldValueChanged, true);
            app.AcquiredstacksizeEditField.Value = 1;
            app.DICreconstructionPanel = uipanel(app.VisualizationTooloptionsUIFigure);
            app.DICreconstructionPanel.Title = 'DIC reconstruction';
            app.IterationsLabel = uilabel(app.DICreconstructionPanel);
            app.IterationsLabel.Text = 'Iterations:';
            app.IterationsEditField = uieditfield(app.DICreconstructionPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 1;
            app.DirectiondegreesLabel = uilabel(app.DICreconstructionPanel);
            app.DirectiondegreesLabel.Text = 'Direction (degrees):';
            app.DirectiondegreesEditField = uieditfield(app.DICreconstructionPanel, 'numeric');
            app.DirectiondegreesEditField.ValueChangedFcn = createCallbackFcn(app, @DirectiondegreesEditFieldValueChanged, true);
            app.StepsizeweightLabel = uilabel(app.DICreconstructionPanel);
            app.StepsizeweightLabel.Text = 'Step size weight:';
            app.StepsizeweightEditField = uieditfield(app.DICreconstructionPanel, 'numeric');
            app.StepsizeweightEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeweightEditFieldValueChanged, true);
            app.SmoothnessweightLabel = uilabel(app.DICreconstructionPanel);
            app.SmoothnessweightLabel.Text = 'Smoothness weight:';
            app.SmoothnessweightEditField = uieditfield(app.DICreconstructionPanel, 'numeric');
            app.SmoothnessweightEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothnessweightEditFieldValueChanged, true);
            app.CoresinathreadEditFieldLabel = uilabel(app.DICreconstructionPanel);
            app.CoresinathreadEditFieldLabel.Text = 'Cores in a thread:';
            app.CoresinathreadEditField = uieditfield(app.DICreconstructionPanel, 'numeric');
            app.CoresinathreadEditField.Limits = [1 Inf];
            app.CoresinathreadEditField.ValueChangedFcn = createCallbackFcn(app, @CoresinathreadEditFieldValueChanged, true);
            app.CoresinathreadEditField.Value = 1;
            app.OKButton = uibutton(app.VisualizationTooloptionsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SaveFindPatchstackCheckBox = uicheckbox(app.VisualizationTooloptionsUIFigure);
            app.SaveFindPatchstackCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveFindPatchstackCheckBoxValueChanged, true);
            app.SaveFindPatchstackCheckBox.Text = 'Save Find&Patch stack';
            % Show the figure after all components are created
            app.VisualizationTooloptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizationToolOptions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizationTooloptionsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizationTooloptionsUIFigure)
        end
    end
end