classdef harvestGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StepsizeEditFieldLabel          matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        MovementwaittimeEditFieldLabel  matlab.ui.control.Label
        MovementwaittimeEditField       matlab.ui.control.NumericEditField
        ExtractionButton                matlab.ui.control.Button
        RetractiondistanceEditFieldLabel  matlab.ui.control.Label
        RetractiondistanceEditField     matlab.ui.control.NumericEditField
        RetractionButton                matlab.ui.control.Button
        VacuumstrengthEditFieldLabel    matlab.ui.control.Label
        VacuumstrengthEditField         matlab.ui.control.NumericEditField
        SucktionlengthEditFieldLabel    matlab.ui.control.Label
        SucktionlengthEditField         matlab.ui.control.NumericEditField
        SucktionButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        model
    end
    
    properties (Access = protected)
        previousAutomaticSlowSpeed
    end

    methods
        function set.model(app, value)
            assert(isa(value, 'GuiModel'));
            app.model = value;
        end
    end
    
    methods (Access = public)
        function initializeForHarvesting(app)
            pipette = app.model.microscope.getPipette(app.model.autopatcher.activePipetteId);
            app.previousAutomaticSlowSpeed = pipette.automaticSlowSpeed;
            pipette.automaticSlowSpeed = 1;
            pipette.switchToAutomaticSlowSpeed();
        end
        
        function extraction(app)
            app.initializeForHarvesting();
            stepSize = app.StepsizeEditField.Value;
            movementWaitTime = app.MovementwaittimeEditField.Value;
            pipette = app.model.microscope.getPipette(app.model.autopatcher.activePipetteId);
            try
                app.model.autopatcher.pressureController.setPressure(-50);
                while app.model.autopatcher.pressureController.getPressure() > -50
                    pause(1);
                end
                directions = ['x', 'y', 'z'];
                for i = 1:2
                    randDirections = directions(randperm(3));
                    randSign = randi(2)-1;
                    if randSign == 0
                        randSign = -1;
                    end
                    for j = 1:numel(randDirections)
                        direction = randDirections(j);
                        if direction == 'x'
                            pipette.move(pipette.x_forward*stepSize, [], [], 'speed', 'slow');
                            pause(movementWaitTime);
                            pipette.move(-pipette.x_forward*stepSize, [], [], 'speed', 'slow');
                            pause(movementWaitTime);
                        elseif direction == 'y'
                            pipette.move([], randSign*pipette.y_forward*stepSize, [], 'speed', 'slow');
                            pause(movementWaitTime);
                            pipette.move([], -randSign*pipette.y_forward*stepSize*2, [], 'speed', 'slow');
                            pause(movementWaitTime*2);
                            pipette.move([], randSign*pipette.y_forward*stepSize, [], 'speed', 'slow');
                            pause(movementWaitTime);
                        else % z
                            pipette.move([], [], randSign*pipette.z_forward*stepSize, 'speed', 'slow');
                            pause(movementWaitTime);
                            pipette.move([], [], -randSign*pipette.z_forward*stepSize*2, 'speed', 'slow');
                            pause(movementWaitTime*2);
                            pipette.move([], [], randSign*pipette.z_forward*stepSize, 'speed', 'slow');
                            pause(movementWaitTime);
                        end
                    end
                end
            catch ex
                ex.message
            end 
            pipette.automaticSlowSpeed = app.previousAutomaticSlowSpeed;
            pipette.switchToAutomaticSlowSpeed();
        end
        
        function retraction(app)
            app.initializeForHarvesting();
            retractionDistance = app.RetractiondistanceEditField.Value;
            pipette = app.model.microscope.getPipette(app.model.autopatcher.activePipetteId);
            try
                app.model.autopatcher.pressureController.setPressure(-80);
                while app.model.autopatcher.pressureController.getPressure() > -80
                    pause(1);
                end
                pipette.move(-pipette.x_forward*retractionDistance, [], [], 'speed', 'slow');
            catch ex
                ex.message
            end 
            pipette.automaticSlowSpeed = app.previousAutomaticSlowSpeed;
            pipette.switchToAutomaticSlowSpeed();
        end
        
        function nucleusSucction(app)
            app.initializeForHarvesting();
            vacuumStrength = app.VacuumstrengthEditField.Value;
            sucktionLength = app.SucktionlengthEditField.Value;
            pipette = app.model.microscope.getPipette(app.model.autopatcher.activePipetteId);
            try
                app.model.autopatcher.pressureController.setPressure(vacuumStrength);
                pause(sucktionLength);
                app.model.autopatcher.pressureController.setPressure(0);
            catch ex
                ex.message
            end 
            pipette.automaticSlowSpeed = app.previousAutomaticSlowSpeed;
            pipette.switchToAutomaticSlowSpeed();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExtractionButton
        function ExtractionButtonPushed(app, event)
            app.extraction();
        end

        % Button pushed function: RetractionButton
        function RetractionButtonPushed(app, event)
            app.retraction();
        end

        % Button pushed function: SucktionButton
        function SucktionButtonPushed(app, event)
            app.nucleusSucction();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StepsizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepsizeEditFieldLabel.Text = 'Step size:';
            app.StepsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsizeEditField.Limits = [0 Inf];
            app.StepsizeEditField.Value = 2;
            app.MovementwaittimeEditFieldLabel = uilabel(app.UIFigure);
            app.MovementwaittimeEditFieldLabel.Text = 'Movement wait time:';
            app.MovementwaittimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MovementwaittimeEditField.Limits = [0 Inf];
            app.MovementwaittimeEditField.Value = 3;
            app.ExtractionButton = uibutton(app.UIFigure, 'push');
            app.ExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractionButtonPushed, true);
            app.ExtractionButton.Text = 'Extraction';
            app.RetractiondistanceEditFieldLabel = uilabel(app.UIFigure);
            app.RetractiondistanceEditFieldLabel.Text = 'Retraction distance:';
            app.RetractiondistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RetractiondistanceEditField.Limits = [0 Inf];
            app.RetractiondistanceEditField.Value = 200;
            app.RetractionButton = uibutton(app.UIFigure, 'push');
            app.RetractionButton.ButtonPushedFcn = createCallbackFcn(app, @RetractionButtonPushed, true);
            app.RetractionButton.Text = 'Retraction';
            app.VacuumstrengthEditFieldLabel = uilabel(app.UIFigure);
            app.VacuumstrengthEditFieldLabel.Text = 'Vacuum strength';
            app.VacuumstrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.VacuumstrengthEditField.Limits = [-Inf 0];
            app.VacuumstrengthEditField.Value = -100;
            app.SucktionlengthEditFieldLabel = uilabel(app.UIFigure);
            app.SucktionlengthEditFieldLabel.Text = 'Sucktion length';
            app.SucktionlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SucktionlengthEditField.Limits = [1 Inf];
            app.SucktionlengthEditField.Value = 60;
            app.SucktionButton = uibutton(app.UIFigure, 'push');
            app.SucktionButton.ButtonPushedFcn = createCallbackFcn(app, @SucktionButtonPushed, true);
            app.SucktionButton.Text = 'Sucktion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = harvestGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end